classdef PVMSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        ClearAllMenu                  matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        SaveScreenshotPDFMenu         matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        DocumentationMenu             matlab.ui.container.Menu
        QuickguideUserManualMenu      matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        Btn_ExportLog                 matlab.ui.control.Button
        Btn_CopyLog                   matlab.ui.control.Button
        Btn_ClearLog                  matlab.ui.control.Button
        AppRunLogTextArea             matlab.ui.control.TextArea
        AppRunLogTextAreaLabel        matlab.ui.control.Label
        ProjectandDataPanel           matlab.ui.container.Panel
        PVmodulefolderLabel           matlab.ui.control.Label
        IVcurvefolderLabel            matlab.ui.control.Label
        DatafolderLabel               matlab.ui.control.Label
        Btn_PVModuleFolderBrowse      matlab.ui.control.Button
        Btn_IVCurveFolderBrowse       matlab.ui.control.Button
        Btn_DataFolderBrowse          matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        ValidateButton                matlab.ui.control.Button
        PVModuleElectricalParametersDatasheetLabel  matlab.ui.control.Label
        Btn_Reset                     matlab.ui.control.Button
        Btn_Import                    matlab.ui.control.Button
        Tbl_Datasheet                 matlab.ui.control.Table
        TabGroup                      matlab.ui.container.TabGroup
        MeasuredIVDataTab             matlab.ui.container.Tab
        MeasuredIVCurveDataLabel      matlab.ui.control.Label
        KeyElectricalPointsSelectedIVCurveLabel  matlab.ui.control.Label
        Btn_ResetIVmeas               matlab.ui.control.Button
        Btn_ImportIVmeas              matlab.ui.control.Button
        Tbl_KeyPoints                 matlab.ui.control.Table
        Tbl_IVCurves                  matlab.ui.control.Table
        Axes_PV_Meas                  matlab.ui.control.UIAxes
        Axes_IV_Meas                  matlab.ui.control.UIAxes
        AlgorithmTab                  matlab.ui.container.Tab
        Btn_ExportParams              matlab.ui.control.Button
        Btn_ResetParams               matlab.ui.control.Button
        Slider_Zoom                   matlab.ui.control.Slider
        ZoomxaxesLabel                matlab.ui.control.Label
        Btn_ExportAlgorithm           matlab.ui.control.Button
        EditField_MaxIteration        matlab.ui.control.NumericEditField
        NRunsLabel_3                  matlab.ui.control.Label
        EditField_RandomSeed          matlab.ui.control.NumericEditField
        NRunsLabel_2                  matlab.ui.control.Label
        ExtractedparametersLabel      matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        StatusLampLabel               matlab.ui.control.Label
        Btn_RunAlgorithm              matlab.ui.control.Button
        AlgorithmLogTextArea          matlab.ui.control.TextArea
        AlgorithmLogTextAreaLabel     matlab.ui.control.Label
        Tbl_ExtractedPar              matlab.ui.control.Table
        Axes_Fine_Conv                matlab.ui.control.UIAxes
        PlotsMetricsTab               matlab.ui.container.Tab
        Btn_ExportMetricsSummary      matlab.ui.control.Button
        Tbl_KPIs                      matlab.ui.control.Table
        Axes_Residuals_vs_Voltage     matlab.ui.control.UIAxes
        Axes_IV_Meas_vs_Calc          matlab.ui.control.UIAxes
        Axes_PV_Meas_vs_Calc          matlab.ui.control.UIAxes
        BatchandReport                matlab.ui.container.Tab
        Btn_CancelBatch               matlab.ui.control.Button
        CheckBox_Useconsecutiveseeds  matlab.ui.control.CheckBox
        EditField_Nruns               matlab.ui.control.NumericEditField
        NRunsLabel                    matlab.ui.control.Label
        Lamp_Batch                    matlab.ui.control.Lamp
        StatusLamp_2Label             matlab.ui.control.Label
        Btn_ExportSummaryBatch        matlab.ui.control.Button
        Tbl_RunsBatch                 matlab.ui.control.Table
        ExtractedparametersLabel_3    matlab.ui.control.Label
        Btn_RunBatch                  matlab.ui.control.Button
        ExtractedparametersLabel_2    matlab.ui.control.Label
        Tbl_BatchSummary              matlab.ui.control.Table
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)
        ProjectRoot   (1,1) string = ""
        IVFolder      (1,1) string = ""
        ModulesFolder (1,1) string = ""
        Paths         struct = struct()
        IVData = {}   % cell array de structs con V,I,meta
        SelectedCurveIndex (1,1) double = NaN   % <-- selector curva
        ConvCache struct = struct()
        BatchCancelRequested (1,1) logical = false
    end

    methods (Access = private)

        function resetToInitialState(app)
            % ===== 0) Estado interno =====
            app.ProjectRoot        = "";
            app.IVFolder           = "";
            app.ModulesFolder      = "";
            app.Paths              = struct();
            app.IVData             = {};
            app.SelectedCurveIndex = NaN;
            app.ConvCache          = struct();

            % ===== 1) Controles de ejecución =====
            app.Btn_RunAlgorithm.Enable = 'off';
            app.Btn_RunBatch.Enable     = 'off';

            % Export Params disabled by default
            if isprop(app,'Btn_ExportParams') && ~isempty(app.Btn_ExportParams) && isvalid(app.Btn_ExportParams)
                app.Btn_ExportParams.Enable = 'off';
            end

            % Lamps
            if isprop(app,'StatusLamp') && isvalid(app.StatusLamp)
                app.StatusLamp.Color = [0.5 0.5 0.5];
            end
            if isprop(app,'StatusLampLabel') && isvalid(app.StatusLampLabel)
                app.StatusLampLabel.Text = 'Idle';
            end
            if isprop(app,'Lamp_Batch') && isvalid(app.Lamp_Batch)
                app.Lamp_Batch.Color = [0.5 0.5 0.5];
            end
            if isprop(app,'StatusLamp_2Label') && isvalid(app.StatusLamp_2Label)
                app.StatusLamp_2Label.Text = 'Idle';
            end

            % Slider zoom
            if isprop(app,'Slider_Zoom') && isvalid(app.Slider_Zoom)
                app.Slider_Zoom.Limits = [0 100];
                app.Slider_Zoom.Value  = 100;
            end

            % ===== 2) Botones "Browse" =====
            if isprop(app,'Btn_DataFolderBrowse') && isvalid(app.Btn_DataFolderBrowse)
                app.Btn_DataFolderBrowse.Text    = 'Browse...';
                app.Btn_DataFolderBrowse.Tooltip = '';
            end
            if isprop(app,'Btn_IVCurveFolderBrowse') && isvalid(app.Btn_IVCurveFolderBrowse)
                app.Btn_IVCurveFolderBrowse.Text    = 'Browse...';
                app.Btn_IVCurveFolderBrowse.Tooltip = '';
            end
            if isprop(app,'Btn_PVModuleFolderBrowse') && isvalid(app.Btn_PVModuleFolderBrowse)
                app.Btn_PVModuleFolderBrowse.Text    = 'Browse...';
                app.Btn_PVModuleFolderBrowse.Tooltip = '';
            end

            % ===== 3) Tablas: datasheet =====
            if isprop(app,'Tbl_Datasheet') && isvalid(app.Tbl_Datasheet)
                app.Tbl_Datasheet.ColumnName     = {'Value','Unit','Status'};
                app.Tbl_Datasheet.ColumnEditable = [false false false];
                app.Tbl_Datasheet.RowName = {'Pmpp','Voc','Isc','Vmpp',...
                    'Impp','Ns','Np','Ki','Kv','NOCT','Tref','Gref',...
                    'CellLength','CellWidth'};
                app.Tbl_Datasheet.Data = cell(numel(app.Tbl_Datasheet.RowName), 3);
                app.Tbl_Datasheet.ColumnWidth    = {50,50,'Auto'};
            end

            % ===== 4) Tabla curvas medidas =====
            if isprop(app,'Tbl_IVCurves') && isvalid(app.Tbl_IVCurves)
                app.Tbl_IVCurves.ColumnName     = {'ID','G (W/m²)','T (°C)','Date','Points','Valid'};
                app.Tbl_IVCurves.ColumnEditable = [false false false false false false];
                app.Tbl_IVCurves.ColumnFormat   = {'char','numeric','numeric','char','numeric','char'};
                app.Tbl_IVCurves.RowName        = {};
                app.Tbl_IVCurves.Data           = {};
            end

            % ===== 5) Axes “Measured” =====
            if isprop(app,'Axes_IV_Meas') && isvalid(app.Axes_IV_Meas)
                cla(app.Axes_IV_Meas,'reset');
                title(app.Axes_IV_Meas,'I-V Curve: Measured');
                xlabel(app.Axes_IV_Meas,'Voltage, V (V)');
                ylabel(app.Axes_IV_Meas,'Current, I (A)');
                grid(app.Axes_IV_Meas,'on');
            end
            if isprop(app,'Axes_PV_Meas') && isvalid(app.Axes_PV_Meas)
                cla(app.Axes_PV_Meas,'reset');
                title(app.Axes_PV_Meas,'P-V Curve: Measured');
                xlabel(app.Axes_PV_Meas,'Voltage, V (V)');
                ylabel(app.Axes_PV_Meas,'Power, P (W)');
                grid(app.Axes_PV_Meas,'on');
            end

            % ===== 6) Key points table =====
            if isprop(app,'Tbl_KeyPoints') && isvalid(app.Tbl_KeyPoints)
                paramNames = {'Pmpp','Voc','Vmpp','Impp','Isc','FF','η'};
                units      = {'W','V','V','A','A','-','%'};
                dataKP = cell(numel(paramNames),3);
                for k = 1:numel(paramNames)
                    dataKP{k,1} = paramNames{k};
                    dataKP{k,2} = 'Select a curve...';
                    dataKP{k,3} = units{k};
                end
                app.Tbl_KeyPoints.RowName        = {};
                app.Tbl_KeyPoints.ColumnName     = {'Parameter','Value','Unit'};
                app.Tbl_KeyPoints.ColumnEditable = [false false false];
                app.Tbl_KeyPoints.Data           = dataKP;
                app.Tbl_KeyPoints.ColumnWidth    = {80,'auto',40};
            end

            % ===== 7) Algorithm tab tables/log =====
            if isprop(app,'AlgorithmLogTextArea') && isvalid(app.AlgorithmLogTextArea)
                app.AlgorithmLogTextArea.Value = {''};
            end
            if isprop(app,'Tbl_ExtractedPar') && isvalid(app.Tbl_ExtractedPar)
                names = {'Iph','Io1','Io2','Rs','Rsh','n1','n2'};
                units = {'A','A','A','Ω','Ω','-','-'};
                d = cell(numel(names),3);
                for k = 1:numel(names)
                    d{k,1} = names{k};
                    d{k,2} = 'Waiting to run...';
                    d{k,3} = units{k};
                end
                app.Tbl_ExtractedPar.RowName        = {};
                app.Tbl_ExtractedPar.ColumnName     = {'Parameter','Value','Unit'};
                app.Tbl_ExtractedPar.ColumnEditable = [false false false];
                app.Tbl_ExtractedPar.ColumnFormat   = {'char','char','char'};
                app.Tbl_ExtractedPar.Data           = d;
                app.Tbl_ExtractedPar.ColumnWidth    = {80,'auto',40};
            end

            % ===== 8) Metrics tab =====
            if isprop(app,'Tbl_KPIs') && isvalid(app.Tbl_KPIs)
                kpiMetric = {'RMSE','MAE','IAE','RMSE','MAE','IAE','RE'};
                kpiQty    = {'I','I','I','P','P','P','Pmpp'};
                kpiUnits  = {'A','A','A·V','W','W','W·V','%'};
                d = cell(numel(kpiMetric),4);
                for k = 1:numel(kpiMetric)
                    d{k,1} = kpiMetric{k};
                    d{k,2} = kpiQty{k};
                    d{k,3} = 'Waiting to run...';
                    d{k,4} = kpiUnits{k};
                end
                app.Tbl_KPIs.RowName        = {};
                app.Tbl_KPIs.ColumnName     = {'Metric','Variable','Value','Unit'};
                app.Tbl_KPIs.ColumnEditable = [false false false false];
                app.Tbl_KPIs.ColumnFormat   = {'char','char','char','char'};
                app.Tbl_KPIs.Data           = d;
                app.Tbl_KPIs.ColumnWidth    = {60,70,'auto',50};
            end

            % Plots & metrics axes
            axList = {'Axes_IV_Meas_vs_Calc','Axes_PV_Meas_vs_Calc','Axes_Residuals_vs_Voltage','Axes_Fine_Conv'};
            for i = 1:numel(axList)
                if isprop(app,axList{i}) && isvalid(app.(axList{i}))
                    cla(app.(axList{i}),'reset');
                    grid(app.(axList{i}),'on');
                end
            end
            if isprop(app,'Axes_Fine_Conv') && isvalid(app.Axes_Fine_Conv)
                title(app.Axes_Fine_Conv,'Curve Convergence');
                xlabel(app.Axes_Fine_Conv,'Iteration');
                ylabel(app.Axes_Fine_Conv,'Best-so-far RMSE');
            end

            % ===== 9) Batch tab =====
            if isprop(app,'Tbl_BatchSummary') && isvalid(app.Tbl_BatchSummary)
                app.Tbl_BatchSummary.ColumnName     = {'Model','Total Runs','Best','Worst','Mean','Std','Time (s/run)'};
                app.Tbl_BatchSummary.ColumnEditable = [false false false false false false false];
                app.Tbl_BatchSummary.Data           = {};
            end
            if isprop(app,'Tbl_RunsBatch') && isvalid(app.Tbl_RunsBatch)
                app.Tbl_RunsBatch.ColumnName     = {'Run','Seed','RMSE obj','Time (s)','Status'};
                app.Tbl_RunsBatch.ColumnEditable = [false false false false false];
                app.Tbl_RunsBatch.ColumnFormat   = {'numeric','numeric','char','char','char'};
                app.Tbl_RunsBatch.Data           = {};
            end
            if isprop(app,'EditField_Nruns') && isvalid(app.EditField_Nruns)
                app.EditField_Nruns.Limits = [2 500];
                app.EditField_Nruns.Value  = 3;
            end
            if isprop(app,'CheckBox_Useconsecutiveseeds') && isvalid(app.CheckBox_Useconsecutiveseeds)
                app.CheckBox_Useconsecutiveseeds.Value = true;
            end

            % ===== 10) Run log =====
            if isprop(app,'AppRunLogTextArea') && isvalid(app.AppRunLogTextArea)
                app.AppRunLogTextArea.Value = {''};
            end
            app.logMsg('INFO','State reset to initial defaults.');
        end

        % ======================================================= %
        function applyConvergenceZoom(app)

            % Requires cached data
            if ~isfield(app.ConvCache,'it_global') || isempty(app.ConvCache.it_global)
                return
            end

            ax = app.Axes_Fine_Conv;

            it_global   = app.ConvCache.it_global;
            best_so_far = app.ConvCache.best_so_far;

            % Slider % (default 100 if missing)
            z = 100;
            if isprop(app,'Slider_Zoom') && ~isempty(app.Slider_Zoom) && isvalid(app.Slider_Zoom)
                z = app.Slider_Zoom.Value;
            end
            z = max(0, min(100, double(z)));

            % Map percent -> Nshow based on MaxIter from UI (example: 100% -> 80000)
            maxIter = 80000;
            if isfield(app.ConvCache,'maxIterUI') && isfinite(app.ConvCache.maxIterUI)
                maxIter = double(app.ConvCache.maxIterUI);
            end

            Nshow = round((z/100) * maxIter);
            Nshow = max(1, Nshow);
            Nshow = min(Nshow, double(app.ConvCache.itMaxAvail));

            % Update X axis window
            ax.XLim = [0, Nshow];

            % Auto-update Y based on visible points only
            mask = it_global <= Nshow;
            y = best_so_far(mask);

            y = y(isfinite(y));
            if isempty(y)
                ax.YLimMode = 'auto';
                return
            end

            yMin = min(y);
            yMax = max(y);

            % Padding (avoid flat line)
            if yMax == yMin
                pad = max(1e-12, 0.05*abs(yMax));
            else
                pad = 0.05*(yMax - yMin);
            end

            ax.YLim = [yMin - pad, yMax + pad];

            % Optional: update a label if you have one
            if isprop(app,'Label_Zoom') && ~isempty(app.Label_Zoom) && isvalid(app.Label_Zoom)
                app.Label_Zoom.Text = sprintf('Zoom (%%): %.0f | Nshow: %.0e', z, Nshow);
            end
        end

        % ======================================================= %
        function buildPaths(app)
            if strlength(app.ProjectRoot)==0 || ~isfolder(app.ProjectRoot)
                error('Project root is not set or does not exist.');
            end

            % Si no se definieron explícitamente, derivar por convención
            if strlength(app.IVFolder)==0
                d = fullfile(app.ProjectRoot,'data','iv');
                if isfolder(d), app.IVFolder = string(d); end
            end
            if strlength(app.ModulesFolder)==0
                d = fullfile(app.ProjectRoot,'config','modules');
                if isfolder(d), app.ModulesFolder = string(d); end
            end

            app.Paths = struct( ...
                'ProjectRoot',   app.ProjectRoot, ...
                'IVFolder',      app.IVFolder, ...
                'ModulesFolder', app.ModulesFolder ...
                );
        end


        function validatePaths(app)
            app.buildPaths();
            P = app.Paths;

            if strlength(P.IVFolder)==0 || ~isfolder(P.IVFolder)
                error('I-V folder not found. Set it or ensure <ProjectRoot>/data/iv exists.');
            end
            if strlength(P.ModulesFolder)==0 || ~isfolder(P.ModulesFolder)
                error('PV module folder not found. Set it or ensure <ProjectRoot>/config/modules exists.');
            end

            % --- Validación dura: existencia de archivos esperados ---
            ivCandidates = dir(fullfile(P.IVFolder,'*.txt'));
            if isempty(ivCandidates)
                error('No I-V .txt files found in: %s', P.IVFolder);
            end

            modCandidates = [ ...
                dir(fullfile(P.ModulesFolder,'*.txt')); ...
                dir(fullfile(P.ModulesFolder,'*.m')); ...
                dir(fullfile(P.ModulesFolder,'*.mat')) ];
            if isempty(modCandidates)
                error('No module definition files found in: %s', P.ModulesFolder);
            end

            % --- Validación dura: formato mínimo de 1 curva (muestreo rápido) ---
            testIV = fullfile(P.IVFolder, ivCandidates(1).name);
            try
                [~,V,I] = app.readIVCurveTxt_local(testIV);
                if numel(V)<5 || numel(I)<5 || any(~isfinite(V)) || any(~isfinite(I))
                    error('Invalid I-V curve content.');
                end
            catch ME
                error('I-V file failed validation (%s): %s', testIV, ME.message);
            end

            % --- Validación dura: módulo (muestreo rápido) ---
            testMod = fullfile(P.ModulesFolder, modCandidates(1).name);
            try
                if endsWith(lower(testMod),".txt")
                    M = app.loadModuleFromTxt(testMod);
                    spec = app.normalizeFromTxtStruct(M);
                    % Check mínimos que tu algoritmo necesita
                    if ~isfield(spec,'Ns') || ~isfinite(spec.Ns.Value)
                        error('Ns missing/invalid in module file.');
                    end
                    if ~isfield(spec,'Isc') || ~isfinite(spec.Isc.Value)
                        error('Isc missing/invalid in module file.');
                    end
                end
                % Si es .m o .mat, aquí solo se valida existencia.
                % La validación de contenido la haces cuando lo cargues realmente.
            catch ME
                error('Module file failed validation (%s): %s', testMod, ME.message);
            end

            % Log trazable
            app.logMsg('INFO', sprintf('ProjectRoot: %s', P.ProjectRoot));
            app.logMsg('INFO', sprintf('IVFolder: %s | files=%d', P.IVFolder, numel(ivCandidates)));
            app.logMsg('INFO', sprintf('ModulesFolder: %s | files=%d', P.ModulesFolder, numel(modCandidates)));
        end

        % ======================================================= %
        % ===== Helpers: Run log + dropdown management =====
        function [seed,maxIter] = getAlgorithmControls(app)
            % Read from UI components and validate defensively.

            seed = app.EditField_RandomSeed.Value;
            maxIter = app.EditField_MaxIteration.Value;

            % Seed
            if isempty(seed) || ~isfinite(seed)
                seed = 42;
            end
            seed = round(seed);
            seed = min(max(seed, 1), 4294967295);

            % Max iterations
            if isempty(maxIter) || ~isfinite(maxIter)
                maxIter = 80000;
            end
            maxIter = round(maxIter);
            maxIter = min(max(maxIter, 1000), 500000);
        end

        % ======================================================= %
        % ===== Algorithm controls: Seed & Max Iteration =====

        function logMsg(app, level, msg)
            ts = char(datetime('now','Format','yyyy-MM-dd HH:mm:ss'));
            line = sprintf('[%s] %-5s %s', ts, upper(level), msg);

            v = app.AppRunLogTextArea.Value;

            % Normaliza a cellstr columna
            if isstring(v)
                if all(strlength(v)==0)
                    v = {};
                else
                    v = cellstr(v(:));
                end
            elseif ischar(v)
                if isempty(v)
                    v = {};
                else
                    v = {v};
                end
            elseif iscell(v)
                v = v(:);
                if numel(v)==1 && isempty(v{1})
                    v = {};
                end
            else
                v = {};
            end

            app.AppRunLogTextArea.Value = [v; {line}];
            drawnow limitrate
        end

        % ======================================================= %
        function M = loadModuleFromTxt(app, fpath) %#ok<INUSD>
            lines = string(readlines(fpath));
            M = struct();

            for i = 1:numel(lines)
                s = strtrim(lines(i));
                if s == "" || startsWith(s,"%") || startsWith(s,"%%")
                    continue
                end

                % elimina comentarios inline %
                pct = strfind(s,"%");
                if ~isempty(pct)
                    s = strtrim(extractBefore(s, pct(1)));
                end
                if s == "", continue, end

                tok = regexp(s, '^(?<name>[A-Za-z]\w*)\s*=\s*(?<rhs>.+?)\s*;\s*$', 'names');
                if isempty(tok), continue, end

                name = tok.name;
                rhs  = strtrim(string(tok.rhs));

                % string '...'
                if startsWith(rhs,"'") && endsWith(rhs,"'")
                    M.(name) = char(extractBetween(rhs, 2, strlength(rhs)-1));
                    continue
                end

                % NaN
                if strcmpi(rhs,"NaN")
                    M.(name) = NaN;
                    continue
                end

                % numérico
                num = str2double(rhs);
                if ~isnan(num)
                    M.(name) = num;
                else
                    % No se evalúan expresiones por seguridad y trazabilidad.
                    M.(name) = char(rhs);
                end
            end

            % Pmax_W: se calcula solo si Vmpp_val e Impp_val son numéricos
            if ~isfield(M,'Pmax_W')
                if isfield(M,'Vmpp_val') && isfield(M,'Impp_val') && isnumeric(M.Vmpp_val) && isnumeric(M.Impp_val)
                    M.Pmax_W = M.Vmpp_val * M.Impp_val;
                end
            end
        end

        function spec = normalizeFromTxtStruct(app, M) %#ok<INUSD>
            % Mapea nombres de tu txt a claves estándar del GUI

            spec = struct();

            % eléctricos
            spec.Pmax = app.pack(M, {'Pmax_W','Pmax','PmaxE','Pmpp'}, "W");
            spec.Voc  = app.pack(M, {'Voc_val','Voc','Vocn'}, "V");
            spec.Isc  = app.pack(M, {'Isc_val','Isc','Iscn'}, "A");
            spec.Vmpp = app.pack(M, {'Vmpp_val','Vmpp','Vmp'}, "V");
            spec.Impp = app.pack(M, {'Impp_val','Impp','Imp'}, "A");
            spec.Ns   = app.pack(M, {'Ns'}, "cells");
            spec.Np   = app.pack(M, {'Np'}, "strings");

            % coeficientes / ratings
            spec.Ki   = app.pack(M, {'Ki'}, "A/°C");
            spec.Kv   = app.pack(M, {'Kv'}, "V/°C");
            spec.NOCT = app.pack(M, {'NOCT'}, "°C");

            % condiciones de referencia del dataset medido
            spec.Tref = app.pack(M, {'Tref'}, "°C");
            spec.Gref = app.pack(M, {'Gref'}, "W/m^2");

            % geometría de celda (agregar en tu txt)
            spec.CellLength = app.pack(M, {'cell_length_mm','CellLength','cell_length'}, "mm");
            spec.CellWidth  = app.pack(M, {'cell_width_mm','CellWidth','cell_width'}, "mm");

            % validaciones simples
            spec = app.basicChecks(spec);
        end

        function out = pack(app, M, names, unit) %#ok<INUSD>
            out = struct('Value', NaN, 'Unit', unit, 'Status', "MISSING");
            v = [];

            for k = 1:numel(names)
                nm = names{k};
                if isfield(M, nm)
                    v = M.(nm);
                    break
                end
            end

            if isempty(v)
                return
            end

            if isnumeric(v) && isscalar(v)
                out.Value = double(v);
                out.Status = "OK";
            elseif ischar(v) || isstring(v)
                num = str2double(v);
                if ~isnan(num)
                    out.Value = num;
                    out.Status = "OK";
                else
                    out.Status = "INVALID";
                end
            else
                out.Status = "INVALID";
            end
        end

        function spec = basicChecks(app, spec) %#ok<INUSD>
            % checks mínimos (no bloquean; solo marcan WARN)
            if ~isnan(spec.Voc.Value) && spec.Voc.Value <= 0, spec.Voc.Status = "WARN"; end
            if ~isnan(spec.Isc.Value) && spec.Isc.Value <= 0, spec.Isc.Status = "WARN"; end
            if ~isnan(spec.Ns.Value) && (spec.Ns.Value < 1 || mod(spec.Ns.Value,1)~=0), spec.Ns.Status = "WARN"; end
            if ~isnan(spec.Gref.Value) && spec.Gref.Value <= 0, spec.Gref.Status = "WARN"; end
        end

        function fillDatasheetTable(app, spec)
            % Asegura layout
            app.Tbl_Datasheet.ColumnName = {'Value','Unit','Status'};
            app.Tbl_Datasheet.ColumnEditable = [false false false];

            rows = string(app.Tbl_Datasheet.RowName);
            data = cell(numel(rows), 3);

            for i = 1:numel(rows)
                key = rows(i);
                if isfield(spec, key)
                    data{i,1} = spec.(key).Value;
                    data{i,2} = char(spec.(key).Unit);
                    data{i,3} = char(spec.(key).Status);
                else
                    data{i,1} = NaN;
                    data{i,2} = '';
                    data{i,3} = 'MISSING';
                end
            end

            app.Tbl_Datasheet.Data = data;
        end

        function panel = buildPanelFromDatasheet(app)
            % Construye el struct "panel" a partir de table_Datasheet

            t = app.Tbl_Datasheet;
            data = t.Data;
            rows = string(t.RowName);

            % helper interno
            function val = getVal(rowName, defaultVal)
                idx = find(rows == rowName, 1);
                if isempty(idx) || idx > size(data,1)
                    val = defaultVal;
                    return
                end
                raw = data{idx,1};
                if isnumeric(raw)
                    val = raw;
                else
                    vnum = str2double(string(raw));
                    if isfinite(vnum)
                        val = vnum;
                    else
                        val = defaultVal;
                    end
                end
            end

            panel = struct();
            panel.modelo   = 'Module'; % opcional: podrías extraerlo del nombre de archivo

            panel.Pmax_W   = getVal("Pmax",  NaN);
            panel.Voc_ref  = getVal("Voc",   NaN);
            panel.Isc      = getVal("Isc",   NaN);
            panel.Vmpp_ref = getVal("Vmpp",  NaN);
            panel.Impp_ref = getVal("Impp",  NaN);
            panel.Ns       = getVal("Ns",    NaN);
            panel.N_pv     = getVal("Np",    NaN);

            panel.Gref     = getVal("Gref", 1000);
            panel.Tref_C   = getVal("Tref", 25);

            panel.kIsc     = getVal("Ki",   NaN);
            panel.kVoc_pct = getVal("Kv",   NaN);
        end

        function [meta,V,I] = readIVCurveTxt_local(~, filename)

            meta = struct('ID','','G',NaN,'T',NaN,'Date','');

            fid = fopen(filename,'r');
            if fid < 0
                error('Cannot open file: %s', filename);
            end

            data = [];
            while ~feof(fid)
                line = strtrim(fgetl(fid));

                if startsWith(line,'%')
                    if contains(line,'ID=')
                        meta.ID = strtrim(extractAfter(line,'='));
                    elseif contains(line,'G=')
                        meta.G = str2double(extractAfter(line,'='));
                    elseif contains(line,'T=')
                        meta.T = str2double(extractAfter(line,'='));
                    elseif contains(line,'Date=')
                        meta.Date = strtrim(extractAfter(line,'='));
                    end

                elseif isempty(line) || contains(lower(line),'v_meas')
                    continue

                else
                    vals = sscanf(line,'%f,%f');
                    if numel(vals)==2
                        data(end+1,:) = vals'; %#ok<AGROW>
                    end
                end
            end
            fclose(fid);

            V = data(:,1);
            I = data(:,2);

            % Fallback ID
            if isempty(meta.ID)
                [~,name] = fileparts(filename);
                meta.ID = name;
            end
        end


        function logMsgAlgorithm(app, level, msg)
            % Escribe en AlgorithmLogTextArea y opcionalmente en RunLog

            ts = char(datetime('now','Format','yyyy-MM-dd HH:mm:ss'));
            line = sprintf('[%s] %-5s %s', ts, upper(level), msg);

            v = app.AlgorithmLogTextArea.Value;

            if isstring(v)
                if all(strlength(v)==0)
                    v = {};
                else
                    v = cellstr(v(:));
                end
            elseif ischar(v)
                if isempty(v)
                    v = {};
                else
                    v = {v};
                end
            elseif iscell(v)
                v = v(:);
                if numel(v)==1 && isempty(v{1})
                    v = {};
                end
            else
                v = {};
            end

            app.AlgorithmLogTextArea.Value = [v; {line}];

            % Si quieres que también quede en el Run Log, descomenta:
            app.logMsg(level, msg);

            drawnow limitrate
        end

        function updateAlgorithmPlots(app, runout)
            % I-V y P-V medido vs calculado
            V = runout.V_meas;
            I_meas = runout.I_meas;
            I_calc = runout.I_calc;
            P_meas = runout.P_meas;
            P_calc = runout.P_calc;

            % I-V
            ax = app.Axes_IV_Meas_vs_Calc;
            cla(ax,'reset');   % resetea propiedades además de borrar hijos
            plot(app.Axes_IV_Meas_vs_Calc, V, I_meas, 'o', ...
                'DisplayName','Measured');
            hold(app.Axes_IV_Meas_vs_Calc,'on');
            plot(app.Axes_IV_Meas_vs_Calc, V, I_calc, '-', ...
                'DisplayName','Calculated');
            grid(app.Axes_IV_Meas_vs_Calc,'on');
            xlabel(app.Axes_IV_Meas_vs_Calc,'Voltage (V)');
            ylabel(app.Axes_IV_Meas_vs_Calc,'Current (A)');
            title(app.Axes_IV_Meas_vs_Calc, 'I-V Measured vs Calculated');
            lgd = legend(app.Axes_IV_Meas_vs_Calc, 'Location', 'best');
            lgd.Box   = 'off';   %
            lgd.Color = 'none';  %

            % P-V
            ax = app.Axes_PV_Meas_vs_Calc;
            cla(ax,'reset');  % resetea propiedades además de borrar hijos
            plot(app.Axes_PV_Meas_vs_Calc, V, P_meas, 'o', ...
                'DisplayName','Measured');
            hold(app.Axes_PV_Meas_vs_Calc,'on');
            plot(app.Axes_PV_Meas_vs_Calc, V, P_calc, '-', ...
                'DisplayName','Calculated');
            grid(app.Axes_PV_Meas_vs_Calc,'on');
            xlabel(app.Axes_PV_Meas_vs_Calc,'Voltage (V)');
            ylabel(app.Axes_PV_Meas_vs_Calc,'Power (W)');
            title(app.Axes_PV_Meas_vs_Calc, 'P-V Measured vs Calculated');
            lgd = legend(app.Axes_PV_Meas_vs_Calc, 'Location', 'best');
            lgd.Box   = 'off';   %
            lgd.Color = 'none';  %

            % -------------------------
            % Fine convergence plot (cache + zoomable)
            % -------------------------
            ax = app.Axes_Fine_Conv;
            cla(ax,'reset');

            if isfield(runout,'hist2') && ~isempty(runout.hist2)

                h = runout.hist2;

                sidx = h(:,1);
                it   = h(:,2);

                iters_per_start = max(it);
                it_global = sidx * iters_per_start + it;

                best_start  = h(:,3);
                best_so_far = cummin(best_start);

                % Cache for slider-driven updates
                app.ConvCache.it_global    = it_global(:);
                app.ConvCache.best_so_far  = best_so_far(:);
                app.ConvCache.itMaxAvail   = max(it_global);
                [~, maxIter] = app.getAlgorithmControls();
                app.ConvCache.maxIterUI    = maxIter;

                % Plot full data once
                plot(ax, it_global, best_so_far, '-', 'DisplayName','RS7MSA method');
                grid(ax,'on');
                xlabel(ax,'Iteration');
                ylabel(ax,'Best-so-far RMSE');
                title(ax,'Curve Convergence');
                lgd = legend(ax,'Location','best'); lgd.Box='off'; lgd.Color='none';

                % Apply current zoom (XLim + auto YLim) using slider value
                app.applyConvergenceZoom();

            else
                title(ax,'Curve Convergence (not available)');
            end

        end


        function setTableCalculating(~, tbl, valueColIdx, txt)
            if nargin < 4, txt = "Calculating..."; end
            data = tbl.Data;
            if isempty(data), return; end
            nRows = size(data,1);
            for r = 1:nRows
                data{r,valueColIdx} = char(txt);
            end
            tbl.Data = data;
            drawnow;  % usa drawnow "fuerte" aquí (no limitrate)
        end


        function summary = runBatchNRuns(app, panel, V_meas, I_meas, meta, N, useConsecutive)

            % Reset cancel flag at the start of every batch run
            app.BatchCancelRequested = false;

            if nargin < 7 || isempty(useConsecutive)
                useConsecutive = true;
            end

            if isempty(N) || ~isfinite(N) || N < 2
                error('Total Runs must be >= 2.');
            end
            N = round(N);

            % baseSeed = 42;
            [baseSeed, ~] = app.getAlgorithmControls();

            if useConsecutive
                seeds = baseSeed + (0:N-1);
            else
                rng(baseSeed,'twister');         % reproducible
                seeds = randi(1e9, [N,1]);       % semillas no consecutivas
            end

            rm = nan(N,1);
            tRun = nan(N,1);

            modelName = "Module";
            if isfield(panel,'modelo') && ~isempty(panel.modelo)
                modelName = string(panel.modelo);
            end

            app.logMsgAlgorithm('INFO', sprintf('Batch started: Model=%s | N=%d | consecutive=%d', ...
                modelName, N, useConsecutive));

            % Pre-fill table with pending rows
            data0 = cell(N,5);
            for i = 1:N
                data0{i,1} = i;          % Run
                data0{i,2} = seeds(i);   % Seed
                data0{i,3} = 'Pending';  % RMSE obj
                data0{i,4} = '...';      % Time (s)
                data0{i,5} = 'WAIT';     % Status
            end
            app.Tbl_RunsBatch.Data = data0;
            drawnow limitrate

            % time
            tTotal = tic;

            for i = 1:N

                % ---- cooperative cancel check ----
                if app.BatchCancelRequested
                    app.logMsgAlgorithm('INFO', sprintf('Batch canceled at run %d/%d.', i-1, N));

                    % Marca estado en la tabla si quieres
                    d = app.Tbl_RunsBatch.Data;
                    if ~isempty(d) && i <= size(d,1)
                        d{i,5} = 'CANCELED';
                        app.Tbl_RunsBatch.Data = d;
                    end

                    % Señaliza hacia arriba con un error controlado
                    error('BATCH:CANCELED','Batch canceled by user.');
                end
                % ---- end cooperative cancel check ----

                meta_i = meta;
                meta_i.Seed = double(seeds(i));     % requiere core leyendo meta.Seed

                t0 = tic;
                runout_i = RS7MSA(panel, V_meas, I_meas, meta_i, []);
                tRun(i) = toc(t0);

                rm(i) = runout_i.rmse_obj;


                d = app.Tbl_RunsBatch.Data;

                if isfinite(rm(i))
                    d{i,3} = sprintf('%.6e', rm(i));
                    d{i,5} = 'OK';
                else
                    d{i,3} = 'NaN';
                    d{i,5} = 'FAIL';
                end
                d{i,4} = sprintf('%.3f', tRun(i));

                app.Tbl_RunsBatch.Data = d;

                % refresco UI (no en cada iteración si N es grande)
                if mod(i,1) == 0  % cambia a 2,5,10 si quieres menos refresh
                    drawnow limitrate
                end


                if mod(i, max(1,ceil(N/10))) == 0 || i==N
                    app.logMsgAlgorithm('INFO', sprintf('Batch %d/%d | last RMSE_obj=%.6e', i, N, rm(i)));
                    drawnow limitrate
                end
            end

            totalTime = toc(tTotal);

            ok = isfinite(rm);
            if ~any(ok)
                error('All batch RMSE values are non-finite.');
            end

            summary = struct();
            summary.model      = modelName;
            summary.N          = sum(ok);
            summary.best       = min(rm(ok));
            summary.worst      = max(rm(ok));
            summary.mean       = mean(rm(ok));
            summary.std        = std(rm(ok),0);
            summary.time_s_run = mean(tRun,'omitnan');
            summary.time_total = totalTime;
            summary.seeds      = seeds(:);
            summary.rmseVec    = rm(:);
            summary.tRun       = tRun(:);

            app.logMsgAlgorithm('INFO', sprintf('Batch done: Best=%.6e Worst=%.6e Mean=%.6e Std=%.6e Time=%.3fs/run', ...
                summary.best, summary.worst, summary.mean, summary.std, summary.time_s_run));
        end


        function addProjectPaths(app)
            % Detecta root del proyecto de forma robusta
            appFile = which('PVMSim');  % devuelve .mlapp o .m según cómo se ejecute
            if isempty(appFile)
                root = pwd;
            else
                appDir = fileparts(appFile);

                % Si PVMSim está dentro de /app, root es el padre
                if endsWith(appDir, [filesep 'app'])
                    root = fileparts(appDir);
                else
                    root = appDir;
                end
            end

            % Añadir SOLO carpetas necesarias (evita genpath sobre outputs/data)
            coreDir     = fullfile(root,'core');
            configDir   = fullfile(root,'config');
            pipelineDir = fullfile(root,'pipeline');

            if isfolder(coreDir),     addpath(genpath(coreDir));     end
            if isfolder(configDir),   addpath(genpath(configDir));   end
            if isfolder(pipelineDir), addpath(genpath(pipelineDir)); end

            % (opcional) log
            app.logMsg('INFO', sprintf('Project root: %s', root));
            app.logMsg('INFO', sprintf('Paths added: core/config/pipeline'));
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.resetToInitialState();

            if isprop(app,'Slider_Zoom') && ~isempty(app.Slider_Zoom) && isvalid(app.Slider_Zoom)
                app.Slider_Zoom.Limits = [0 100];
                app.Slider_Zoom.Value  = 100;
            end

            app.Btn_RunAlgorithm.Enable = 'off';
            app.Btn_RunBatch.Enable     = 'off';

            app.addProjectPaths();  % <-- añadir paths de carpetas del proyecto

            % Inicializa Run Log
            app.AppRunLogTextArea.Value = {''};

            app.logMsg('INFO',['App started. Select root project folder, ' ...
                'I-V curve and PV module folders.']);

            % Inicializa table Datasheet
            app.Tbl_Datasheet.ColumnName = {'Value','Unit','Status'};
            app.Tbl_Datasheet.ColumnEditable = [false false false];
            app.Tbl_Datasheet.RowName = {'Pmpp','Voc','Isc','Vmpp',...
                'Impp','Ns','Np','Ki','Kv','NOCT','Tref','Gref',...
                'CellLength','CellWidth'};
         
            % --- Axes labels ---
            cla(app.Axes_IV_Meas); cla(app.Axes_PV_Meas);

            title(app.Axes_IV_Meas,'I-V Curve: Measured ');
            xlabel(app.Axes_IV_Meas,'Voltage, V (V)');
            ylabel(app.Axes_IV_Meas,'Current, I (A)');

            title(app.Axes_PV_Meas,'P-V Curve: Measured ');
            xlabel(app.Axes_PV_Meas,'Voltage, V (V)');
            ylabel(app.Axes_PV_Meas,'Power, P (W)');

            % --- KPIs table (Metric + Quantity separated) ---
            kpiMetric = { ...
                'RMSE','MAE','IAE','RMSE','MAE','IAE','RE'};

            kpiQty = { ...
                'I','I','I','P','P','P','Pmpp'};

            kpiUnits = {'A','A','A·V','W','W','W·V','%'};

            n = numel(kpiMetric);
            data = cell(n,4);
            for k = 1:n
                data{k,1} = kpiMetric{k};          % Metric
                data{k,2} = kpiQty{k};             % Quantity
                data{k,3} = 'Waiting to run...';   % Value
                data{k,4} = kpiUnits{k};           % Unit
            end

            app.Tbl_KPIs.RowName = {};
            app.Tbl_KPIs.ColumnName = {'Metric','Variable','Value','Unit'};
            app.Tbl_KPIs.ColumnEditable = [false false false false];
            app.Tbl_KPIs.Data = data;
            app.Tbl_KPIs.ColumnFormat = {'char','char','char','char'};
            app.Tbl_KPIs.ColumnWidth = {60, 70, 'auto', 50};

            % --- Extracted parameters table ---
            table_ExtractedParametersNames = { ...
                'Iph', ...
                'Io1', ...
                'Io2', ...
                'Rs', ...
                'Rsh', ...
                'n1', ...
                'n2'};
            ExtractedParametersunits = {'A','A','A','Ω','Ω','-','-'};
            app.Tbl_ExtractedPar.ColumnName   = {'Parameter','Value','Unit'};
            app.Tbl_ExtractedPar.ColumnEditable = [false false false];
            app.Tbl_ExtractedPar.ColumnFormat = {'char','char','char'};  % Value como texto

            n = numel(table_ExtractedParametersNames);
            data = cell(n,3);
            for k = 1:n
                data{k,1} = table_ExtractedParametersNames{k}; % Parameter
                data{k,2} = 'Waiting to run...';  % Value
                data{k,3} = ExtractedParametersunits{k}; % Unit
            end

            app.Tbl_ExtractedPar.RowName = {}; % sin row names
            app.Tbl_ExtractedPar.ColumnName = {'Parameter','Value','Unit'};
            app.Tbl_ExtractedPar.ColumnEditable = [false false false];
            app.Tbl_ExtractedPar.Data = data;
            app.Tbl_ExtractedPar.ColumnFormat   = {'char','char','char'};   % <-- CLAVE
            % Ancho por columna: Parameter, Value, Unit (en píxeles)
            app.Tbl_ExtractedPar.ColumnWidth = {80, 'auto', 40};

            % --- Key Electrical Points table ---
            paramNames = {'Pmpp','Voc','Vmpp','Impp','Isc','FF','η'};
            units      = {'W','V','V','A','A','-','%'};

            n = numel(paramNames);
            data = cell(n,3);
            for k = 1:n
                data{k,1} = paramNames{k}; % Parameter
                data{k,2} = 'Select a curve...';  % Value
                data{k,3} = units{k}; % Unit
            end

            app.Tbl_KeyPoints.RowName = {}; % sin row names
            app.Tbl_KeyPoints.ColumnName = {'Parameter','Value','Unit'};
            app.Tbl_KeyPoints.ColumnEditable = [false false false];
            app.Tbl_KeyPoints.Data = data;
            % Ancho por columna: Parameter, Value, Unit (en píxeles)
            app.Tbl_KeyPoints.ColumnWidth = {80, 'auto', 40};

            % --- Batch summary table (Batch & Report tab) ---
            app.Tbl_BatchSummary.ColumnName     = {'Model','Total Runs','Best','Worst','Mean','Std','Time (s/run)'};
            app.Tbl_BatchSummary.ColumnEditable = [false false false false false false false];
            app.Tbl_BatchSummary.ColumnFormat   = {'char','numeric','char','char','char','char','char'};
            app.Tbl_BatchSummary.Data           = {};   % sin filas al inicio
            app.Tbl_BatchSummary.ColumnWidth    = {140, 60, 90, 90, 90, 90, 90};

            % --- Batch controls defaults ---
            app.EditField_Nruns.Limits = [2 500];          % evita 0 (como en tu screenshot)
            app.EditField_Nruns.Value  = 3;                % típico para estadísticos tipo Table 15
            app.CheckBox_Useconsecutiveseeds.Value = true;

            % --- Runs detail table (Batch & Report tab) ---
            app.Tbl_RunsBatch.RowName        = {};
            app.Tbl_RunsBatch.ColumnName     = {'Run','Seed','RMSE obj','Time (s)','Status'};
            app.Tbl_RunsBatch.ColumnEditable = [false false false false false];

            % Usar 'char' para RMSE/Time evita formateos raros y te permite %.6e
            app.Tbl_RunsBatch.ColumnFormat   = {'numeric','numeric','char','char','char'};
            % app.Tbl_RunsBatch.ColumnWidth    = {50, 90, 120, 90, 70};
            app.Tbl_RunsBatch.Data           = {};   % se sobreescribe en cada batch

            % --- Algorithm controls: Seed & Max Iteration (validation at UI level) ---
            app.EditField_RandomSeed.Limits = [1 4294967295];
            app.EditField_RandomSeed.RoundFractionalValues = 'on';
            if ~isfinite(app.EditField_RandomSeed.Value) || app.EditField_RandomSeed.Value < 1
                app.EditField_RandomSeed.Value = 42;
            end

            app.EditField_MaxIteration.Limits = [1000 500000];
            app.EditField_MaxIteration.RoundFractionalValues = 'on';
            if ~isfinite(app.EditField_MaxIteration.Value) || app.EditField_MaxIteration.Value < 1000
                app.EditField_MaxIteration.Value = 80000;
            end


            % Disable export params until a successful run
            if isprop(app,'Btn_ExportParams') && ~isempty(app.Btn_ExportParams) && isvalid(app.Btn_ExportParams)
                app.Btn_ExportParams.Enable = 'off';
            end

        end

        % Button pushed function: Btn_Import
        function Btn_ImportPushed(app, event)
            % --- Default folder: use validated Modules folder (preferred) ---
            defaultDir = pwd;

            % 1) Prefer app.ModulesFolder if it exists
            if isprop(app,'ModulesFolder') && strlength(app.ModulesFolder)>0 && isfolder(app.ModulesFolder)
                defaultDir = char(app.ModulesFolder);

                % 2) Else, try ProjectRoot\config\modules (convention)
            elseif isprop(app,'ProjectRoot') && strlength(app.ProjectRoot)>0 && isfolder(app.ProjectRoot)
                cand = fullfile(char(app.ProjectRoot),'config','modules');
                if isfolder(cand)
                    defaultDir = cand;
                    % opcional: cachear para futuras importaciones
                    if isprop(app,'ModulesFolder')
                        app.ModulesFolder = string(cand);
                    end
                else
                    defaultDir = char(app.ProjectRoot);
                end
            end

            [file, path] = uigetfile( ...
                {'*.txt','PV module data (*.txt)'}, ...
                'Select PV module data (.txt)', ...
                defaultDir);

            if isequal(file,0)
                app.logMsg('INFO','Module import canceled.');
                return
            end

            fpath = fullfile(path,file);

            try
                M    = app.loadModuleFromTxt(fpath);      % struct raw
                spec = app.normalizeFromTxtStruct(M);     % struct normalizado para tabla
            catch ME
                app.logMsg('ERROR', sprintf('PV module data load failed: %s', ME.message));
                return
            end

            app.fillDatasheetTable(spec);
            app.logMsg('INFO', sprintf('PV module data loaded from .txt: %s', fpath));
        end

        % Button pushed function: Btn_Reset
        function Btn_ResetPushed(app, event)
            % Limpia tabla (mantiene RowName y ColumnName)
            nRows = numel(app.Tbl_Datasheet.RowName);
            nCols = numel(app.Tbl_Datasheet.ColumnName);

            app.Tbl_Datasheet.Data = cell(nRows, nCols);

            app.ProjectRoot   = "";
            app.IVFolder      = "";
            app.ModulesFolder = "";
            app.Paths         = struct();

            app.Btn_DataFolderBrowse.Text = 'Browse...';
            app.Btn_DataFolderBrowse.Tooltip = '';

            app.Btn_IVCurveFolderBrowse.Text = 'Browse...';
            app.Btn_IVCurveFolderBrowse.Tooltip = '';

            app.Btn_PVModuleFolderBrowse.Text = 'Browse...';
            app.Btn_PVModuleFolderBrowse.Tooltip = '';

            % bloquear Run hasta re-validar
            app.Btn_RunAlgorithm.Enable = 'off';
            app.Btn_RunBatch.Enable     = 'off';

            app.logMsg('INFO','Datasheet table reset.');
        end

        % Button pushed function: Btn_ClearLog
        function Btn_ClearLogPushed(app, event)
            app.AppRunLogTextArea.Value = {''};
            app.logMsg('INFO','Run log cleared.');
        end

        % Button pushed function: Btn_CopyLog
        function Btn_CopyLogPushed(app, event)
            v = app.AppRunLogTextArea.Value;

            % Normaliza a texto
            if isstring(v)
                txt = strjoin(v(:), newline);
            elseif ischar(v)
                txt = string(v);
            elseif iscell(v)
                % cellstr esperado
                txt = strjoin(string(v(:)), newline);
            else
                txt = "";
            end

            try
                clipboard('copy', char(txt));
                app.logMsg('INFO', sprintf('Run log copied to clipboard (%d chars).', strlength(txt)));
            catch ME
                app.logMsg('ERROR', sprintf('Clipboard copy failed: %s', ME.message));
            end
        end

        % Button pushed function: Btn_ExportLog
        function Btn_ExportLogPushed(app, event)
            v = app.AppRunLogTextArea.Value;

            if isstring(v)
                lines = v(:);
            elseif ischar(v)
                lines = string(v);
            elseif iscell(v)
                lines = string(v(:));
            else
                lines = string.empty;
            end

            % Quita líneas vacías iniciales típicas
            lines = lines(strlength(lines) > 0);

            [file, path] = uiputfile( ...
                {'*.txt','Text file (*.txt)'; '*.log','Log file (*.log)'; '*.*','All files'}, ...
                'Export run log', ...
                'run_log.txt');

            if isequal(file,0)
                app.logMsg('INFO','Run log export canceled.');
                return
            end

            out = fullfile(path, file);

            try
                writelines(lines, out);
                app.logMsg('INFO', sprintf('Run log exported: %s', out));
            catch ME
                app.logMsg('ERROR', sprintf('Run log export failed: %s', ME.message));
            end
        end

        % Button pushed function: Btn_ImportIVmeas
        function Btn_ImportIVmeasPushed(app, event)

            checkSymbol = char(10003);  % ✓
            crossSymbol = char(10007);  % ✗

            nImported = 0;

            existingData = app.Tbl_IVCurves.Data;
            if isempty(existingData)
                existingIDs = {};
            else
                existingIDs = existingData(:,1);   % ID column
            end

            % --- Default folder: use validated I–V folder (preferred) ---
            defaultDir = pwd;

            % 1) Prefer app.IVFolder if it exists
            if isprop(app,'IVFolder') && strlength(app.IVFolder)>0 && isfolder(app.IVFolder)
                defaultDir = char(app.IVFolder);

                % 2) Else, try ProjectRoot\data\iv (convention)
            elseif isprop(app,'ProjectRoot') && strlength(app.ProjectRoot)>0 && isfolder(app.ProjectRoot)
                cand = fullfile(char(app.ProjectRoot),'data','iv');
                if isfolder(cand)
                    defaultDir = cand;
                    % opcional: cachear para futuras importaciones
                    if isprop(app,'IVFolder')
                        app.IVFolder = string(cand);
                    end
                else
                    defaultDir = char(app.ProjectRoot);
                end
            end

            [files, path] = uigetfile( ...
                {'*.txt','Measured I–V curve (*.txt)'}, ...
                'Select measured I–V curve(s)', ...
                defaultDir, ...
                'MultiSelect','on');

            if isequal(files,0)
                app.logMsg('INFO','I–V import canceled.');
                return
            end
            if ischar(files)
                files = {files};
            end

            for k = 1:numel(files)

                try
                    [meta,V,I] = app.readIVCurveTxt_local(fullfile(path,files{k}));
                catch ME
                    app.logMsg('WARN', sprintf( ...
                        'File skipped (invalid I–V format): %s | %s', ...
                        files{k}, ME.message));
                    continue
                end

                if isempty(V) || isempty(I) || numel(V) < 2 || numel(I) < 2
                    app.logMsg('WARN', sprintf( ...
                        'File skipped (no valid I–V data): %s', files{k}));
                    continue
                end

                curveID = meta.ID;

                % --- Duplicate ID check ---
                if any(strcmp(existingIDs, curveID))
                    app.logMsg('WARN', sprintf( ...
                        'Curve skipped (duplicate ID): %s', curveID));
                    continue
                end

                % Minimal validation
                isValid = ~(numel(V)<5 || any(~isfinite(V)) || any(~isfinite(I)));

                % Store in memory
                app.IVData{end+1} = struct( ...
                    'ID', curveID, ...
                    'V', V, ...
                    'I', I, ...
                    'G', meta.G, ...
                    'T', meta.T, ...
                    'Date', meta.Date, ...
                    'File', files{k});

                % Table row (Valid as symbol)
                validCell = crossSymbol;
                if isValid
                    validCell = checkSymbol;
                end

                row = {curveID, meta.G, meta.T, meta.Date, numel(V), validCell};

                existingData = [existingData; row]; %#ok<AGROW>
                existingIDs  = [existingIDs; {curveID}]; %#ok<AGROW>

                nImported = nImported + 1;
            end

            app.Tbl_IVCurves.Data = existingData;

            if nImported > 0
                app.logMsg('INFO', sprintf('%d I–V curve(s) imported successfully.', nImported));
            else
                app.logMsg('WARN', 'No valid I–V curves were imported.');
            end


        end

        % Button pushed function: Btn_ResetIVmeas
        function Btn_ResetIVmeasPushed(app, event)
            cla(app.Axes_IV_Meas);
            cla(app.Axes_PV_Meas);

            app.Tbl_IVCurves.Data = cell(0,6);
            app.IVData = {};

            app.SelectedCurveIndex = NaN;

            % Reset Key Points table to default placeholders
            try
                paramNames = {'Pmpp','Voc','Vmpp','Impp','Isc','FF','η'};
                units      = {'W','V','V','A','A','-','%'};

                n = numel(paramNames);
                dataKP = cell(n,3);
                for k = 1:n
                    dataKP{k,1} = paramNames{k};
                    dataKP{k,2} = 'Select a curve...';
                    dataKP{k,3} = units{k};
                end

                app.Tbl_KeyPoints.RowName = {};
                app.Tbl_KeyPoints.ColumnName = {'Parameter','Value','Unit'};
                app.Tbl_KeyPoints.ColumnEditable = [false false false];
                app.Tbl_KeyPoints.Data = dataKP;
                app.Tbl_KeyPoints.ColumnWidth = {80,'auto',40};
            catch
                % If the table is not fully initialized yet, avoid blocking reset
            end

            app.logMsg('INFO','All measured curves cleared.');
        end

        % Cell selection callback: Tbl_IVCurves
        function Tbl_IVCurvesCellSelection(app, event)

            if isempty(event.Indices)
                return
            end

            row = event.Indices(1);
            app.SelectedCurveIndex = row;   % <-- guardar selección

            if row < 1 || row > numel(app.IVData)
                return
            end

            data = app.IVData{row};

            V = data.V;
            I = data.I;
            P = V .* I;

            cla(app.Axes_IV_Meas);
            cla(app.Axes_PV_Meas);

            plot(app.Axes_IV_Meas, V, I, '-o');
            grid(app.Axes_IV_Meas,'on');

            plot(app.Axes_PV_Meas, V, P, '-o');
            grid(app.Axes_PV_Meas,'on');

            title(app.Axes_IV_Meas, sprintf('I-V Measured Curve (ID: %s)', data.ID));
            title(app.Axes_PV_Meas, sprintf('P-V Measured Curve (ID: %s)', data.ID));

            % ---------------------------------------------------------
            % Key points (from selected measured curve)
            % ---------------------------------------------------------
            % Isc ~ I at V closest to 0
            [~, idx0] = min(abs(V));
            Isc = I(idx0);

            % Voc ~ V at I closest to 0
            [~, idxI0] = min(abs(I));
            Voc = V(idxI0);

            % MPP
            [Pmpp, idxMpp] = max(P);
            Vmpp = V(idxMpp);
            Impp = I(idxMpp);

            % Fill Key Points table (Property-Value style)
            % Cálculo FF
            den = Isc * Voc;
            if den ~= 0 && isfinite(den)
                FF = Pmpp / den;
            else
                FF = NaN;
            end

            % Si no tienes área del módulo definida, deja η como NaN
            eta = NaN;

            % Fill Key Points table (Parameter | Value | Unit)
            app.Tbl_KeyPoints.Data = {
                'Pmpp', Pmpp, 'W';
                'Voc',   Voc,  'V';
                'Vmpp',  Vmpp, 'V';
                'Impp',  Impp, 'A';
                'Isc',   Isc,  'A';
                'FF',    FF,   '-';
                'η',     eta,  '%';
                };

            app.logMsg('INFO', sprintf( ...
                'Curve selected: ID=%s | Points=%d | G=%.1f | T=%.1f', ...
                data.ID, numel(V), data.G, data.T));

        end

        % Button pushed function: Btn_RunAlgorithm
        function Btn_RunAlgorithmPushed(app, event)
           
            if isprop(app,'Btn_ExportParams') && ~isempty(app.Btn_ExportParams) && isvalid(app.Btn_ExportParams)
                app.Btn_ExportParams.Enable = 'off';
            end

            try
                % evitar que se presione el botón mientras corre Algorithm
                app.Btn_RunAlgorithm.Enable = 'off';
                cleanupObj = onCleanup(@() set(app.Btn_RunAlgorithm,'Enable','on'));

                % 1) Comprobar que hay curvas importadas
                if isempty(app.IVData) || isempty(app.Tbl_IVCurves.Data)
                    uialert(app.UIFigure, ...
                        'No measured I–V curve has been imported.', ...
                        'Algorithm error');
                    return
                end

                % 2) Determinar índice de curva seleccionada
                if ~isnan(app.SelectedCurveIndex) && ...
                        app.SelectedCurveIndex >= 1 && ...
                        app.SelectedCurveIndex <= numel(app.IVData)
                    idx = app.SelectedCurveIndex;
                else
                    idx = 1;  % por defecto la primera
                end

                curve = app.IVData{idx};

                V_meas = curve.V(:);
                I_meas = curve.I(:);

                meta = struct( ...
                    'ID',   curve.ID, ...
                    'G',    curve.G, ...
                    'T',    curve.T, ...
                    'Date', curve.Date);

                % 3) Construir struct panel desde la tabla de datasheet
                panel = app.buildPanelFromDatasheet();

                if ~isfield(panel,'Ns') || ~isfinite(panel.Ns)
                    uialert(app.UIFigure, ...
                        'Module datasheet is incomplete (Ns is missing or invalid).', ...
                        'Algorithm error');
                    return
                end
                if ~isfield(panel,'Isc') || ~isfinite(panel.Isc)
                    uialert(app.UIFigure, ...
                        'Module datasheet is incomplete (Isc is missing or invalid).', ...
                        'Algorithm error');
                    return
                end
                if ~isfield(panel,'Tref_C') || ~isfinite(panel.Tref_C)
                    panel.Tref_C = 25.0;
                end

                % 4) Preparar la función de log para el algoritmo
                logFcn = @(level,msg) app.logMsgAlgorithm(level,msg);

                % 5) Estado visual
                app.StatusLamp.Color = 'yellow';
                app.StatusLampLabel.Text = 'Running';

                % Mostrar "Calculating..." en tablas mientras corre
                app.setTableCalculating(app.Tbl_ExtractedPar, 2, "Calculating...");
                app.setTableCalculating(app.Tbl_KPIs, 2, "Calculating...");


                % --- Read algorithm settings from UI ---
                [seed, maxIter] = app.getAlgorithmControls();

                meta.Seed    = double(seed);
                meta.MaxIter = double(maxIter);

                app.logMsgAlgorithm('INFO', sprintf('Algorithm settings: Seed=%d | MaxIter=%d', seed, maxIter));


                % 6) Llamar al núcleo de optimización
                runout = RS7MSA(panel, V_meas, I_meas, meta, logFcn);

                % % ---------------------------------------------------------
                % % Update KPIs table (Tbl_KPIs)
                % % ---------------------------------------------------------

                k = metrics_kpis(runout);

                fmt6 = @(x) sprintf('%.6f', x);

                app.Tbl_KPIs.Data = {
                    'RMSE','I',    fmt6(k.RMSE_I),  'A';
                    'MAE', 'I',    fmt6(k.MAE_I),   'A';
                    'IAE', 'I',    fmt6(k.IAE_I),   'A·V';
                    'RMSE','P',    fmt6(k.RMSE_P),  'W';
                    'MAE', 'P',    fmt6(k.MAE_P),   'W';
                    'IAE', 'P',    fmt6(k.IAE_P),   'W·V';
                    'RE',  'Pmpp', fmt6(k.RE_Pmpp), '%';
                    };

                % (Opcional) guardar dentro de runout para trazabilidad aguas abajo
                runout.kpis = k;

                % ---------------------------------------------------------
                % Update Extracted Parameters table (Tbl_ExtractedPar)
                % ---------------------------------------------------------

                if ~isfield(runout,'theta7') || isempty(runout.theta7) || ~isnumeric(runout.theta7) || numel(runout.theta7) < 7
                    app.logMsgAlgorithm('ERROR','runout.theta7 not found or invalid. Cannot populate Tbl_ExtractedPar.');

                    if isprop(app,'Btn_ExportParams') && ~isempty(app.Btn_ExportParams) && isvalid(app.Btn_ExportParams)
                        app.Btn_ExportParams.Enable = 'off';
                    end

                else
                    th = runout.theta7(:);

                    Iph = th(1);
                    Io1 = th(2);
                    Io2 = th(3);
                    Rs  = th(4);
                    Rsh = th(5);
                    n1  = th(6);
                    n2  = th(7);

                    % Formateo (scientific para Io1/Io2, normal para el resto)
                    fmtA   = @(x) sprintf('%.4g', x);   % corrientes "normales"
                    fmtSci = @(x) sprintf('%.3e', x);   % Io1/Io2
                    fmtOhm = @(x) sprintf('%.4g', x);   % resistencias
                    fmtN   = @(x) sprintf('%.4f', x);   % idealidades

                    th = runout.theta7(:);
                    app.logMsgAlgorithm('INFO', sprintf( ...
                        'theta7 (for table) Io1=%.3e Io2=%.3e', th(2), th(3)));

                    app.Tbl_ExtractedPar.Data = {
                        'Iph', fmtA(Iph),  'A';
                        'Io1', fmtSci(Io1),'A';
                        'Io2', fmtSci(Io2),'A';
                        'Rs',  fmtOhm(Rs), 'Ω';
                        'Rsh', fmtOhm(Rsh),'Ω';
                        'n1',  fmtN(n1),   '-';
                        'n2',  fmtN(n2),   '-';
                        };
                end

                if isprop(app,'Btn_ExportParams') && ~isempty(app.Btn_ExportParams) && isvalid(app.Btn_ExportParams)
                    app.Btn_ExportParams.Enable = 'on';
                end

                % 7) Actualizar figuras en la pestaña Validation & Metrics y Convergence
                app.updateAlgorithmPlots(runout);
                % ---------------------------------------------------------
                % Residuals (explicit): I_meas - I_calc
                % ---------------------------------------------------------
                if isfield(runout,'res_I') && ~isempty(runout.res_I) ...
                        && isfield(runout,'V_meas') && ~isempty(runout.V_meas)

                    V = runout.V_meas(:);
                    r = runout.res_I(:);

                    % --- Residuals vs Voltage ---
                    cla(app.Axes_Residuals_vs_Voltage);
                    plot(app.Axes_Residuals_vs_Voltage, V, r, '-o');
                    grid(app.Axes_Residuals_vs_Voltage,'on');
                    yline(app.Axes_Residuals_vs_Voltage, 0, '--');

                    title(app.Axes_Residuals_vs_Voltage, 'Current Residuals vs. Voltage');
                    xlabel(app.Axes_Residuals_vs_Voltage, 'Voltage, V (V)');
                    ylabel(app.Axes_Residuals_vs_Voltage, 'Current Residual, |I_{meas}- I_{calc}| (A)');

                else
                    % Si no hay residual disponible, limpiar e informar
                    cla(app.Axes_Residuals_vs_Voltage);
                    app.logMsgAlgorithm('WARN','Residual plots skipped: runout.res_I or runout.V_meas missing.');
                end

                app.logMsgAlgorithm('INFO', sprintf( ...
                    'Finished parameter extraction. RMSE_I = %.4f A', runout.rmse_I));

                app.StatusLamp.Color = 'green';
                app.StatusLampLabel.Text = 'Done';

            catch ME

                if isprop(app,'Btn_ExportParams') && ~isempty(app.Btn_ExportParams) && isvalid(app.Btn_ExportParams)
                    app.Btn_ExportParams.Enable = 'off';
                end

                app.StatusLamp.Color = 'red';
                app.StatusLampLabel.Text = 'Error';

                app.logMsgAlgorithm('ERROR', ME.message);

                uialert(app.UIFigure, ...
                    sprintf('Parameter extraction failed:\n\n%s', ME.message), ...
                    'Algorithm error');
            end
        end

        % Button pushed function: Btn_RunBatch
        function Btn_RunBatchButtonPushed(app, event)

            % % --- Batch status lamp helpers (local) ---
            function setBatchLamp(state)
                % state: 'RUN' | 'DONE' | 'FAIL' | 'IDLE'
                switch upper(string(state))
                    case "RUN"
                        app.Lamp_Batch.Color = [1 1 0];     % yellow
                        app.StatusLamp_2Label.Text = 'Running';
                    case "DONE"
                        app.Lamp_Batch.Color = [0 1 0];     % green
                        app.StatusLamp_2Label.Text = 'Done';
                    case "FAIL"
                        app.Lamp_Batch.Color = [1 0 0];     % red
                        app.StatusLamp_2Label.Text = 'Failed';
                    otherwise
                        app.Lamp_Batch.Color = [0.5 0.5 0.5]; % gray
                        app.StatusLamp_2Label.Text = 'Idle';
                end
                drawnow limitrate
            end

            try
                % bloquear botón mientras corre
                app.Btn_RunBatch.Enable = 'off';
                c = onCleanup(@() set(app.Btn_RunBatch,'Enable','on'));

                % --- set RUN at start ---
                setBatchLamp('RUN');

                % validar N
                N = round(app.EditField_Nruns.Value);
                if ~isfinite(N) || N < 2
                    setBatchLamp('FAIL');
                    uialert(app.UIFigure,'N runs must be >= 2 for batch statistics.','Batch error');
                    return
                end

                % validar que exista curva
                if isempty(app.IVData)
                    setBatchLamp('FAIL');
                    uialert(app.UIFigure,'No measured I–V curve has been imported.','Batch error');
                    return
                end
                if ~isnan(app.SelectedCurveIndex) && app.SelectedCurveIndex>=1 && app.SelectedCurveIndex<=numel(app.IVData)
                    idx = app.SelectedCurveIndex;
                else
                    idx = 1;
                end

                curve = app.IVData{idx};
                V_meas = curve.V(:);
                I_meas = curve.I(:);

                meta = struct('ID',curve.ID,'G',curve.G,'T',curve.T,'Date',curve.Date);

                % panel desde datasheet
                panel = app.buildPanelFromDatasheet();
                if ~isfield(panel,'Ns') || ~isfinite(panel.Ns)
                    setBatchLamp('FAIL');
                    uialert(app.UIFigure,'Module datasheet is incomplete (Ns missing/invalid).','Batch error');
                    return
                end
                if ~isfield(panel,'Isc') || ~isfinite(panel.Isc)
                    setBatchLamp('FAIL');
                    uialert(app.UIFigure,'Module datasheet is incomplete (Isc missing/invalid).','Batch error');
                    return
                end
                if ~isfield(panel,'Tref_C') || ~isfinite(panel.Tref_C)
                    panel.Tref_C = 25.0;
                end

                useConsecutive = logical(app.CheckBox_Useconsecutiveseeds.Value);

                % ejecutar batch y obtener summary
                summary = app.runBatchNRuns(panel, V_meas, I_meas, meta, N, useConsecutive);

                                % ---- fill Runs detail table (N rows) ----
                Nr = numel(summary.rmseVec);
                runsData = cell(Nr,5);

                for i = 1:Nr
                    runsData{i,1} = i;                 % Run index
                    runsData{i,2} = summary.seeds(i);  % Seed

                    if isfinite(summary.rmseVec(i))
                        runsData{i,3} = sprintf('%.6e', summary.rmseVec(i));
                        runsData{i,5} = 'OK';
                    else
                        runsData{i,3} = 'NaN';
                        runsData{i,5} = 'FAIL';
                    end

                    runsData{i,4} = sprintf('%.3f', summary.tRun(i));
                end

                app.Tbl_RunsBatch.Data = runsData;

                % volcar a la tabla (una fila; si quieres acumular, se apendea)
                row = { ...
                    char(summary.model), ...
                    summary.N, ...
                    sprintf('%.6e', summary.best), ...
                    sprintf('%.6e', summary.worst), ...
                    sprintf('%.6e', summary.mean), ...
                    sprintf('%.6e', summary.std), ...
                    sprintf('%.3f', summary.time_s_run) ...
                    };

                data = app.Tbl_BatchSummary.Data;
                if isempty(data), data = {}; end
                app.Tbl_BatchSummary.Data = [data; row];

                app.logMsgAlgorithm('INFO','Batch summary table updated.');

                % --- set DONE at end ---
                setBatchLamp('DONE');

            catch ME
                if strcmp(ME.identifier,'BATCH:CANCELED')
                    % Estado cancelado (no es fallo)
                    try
                        setBatchLamp('IDLE');   % o crea estado "CANCELED" si quieres
                        app.StatusLamp_2Label.Text = 'Canceled';
                    catch
                    end
                    app.logMsgAlgorithm('INFO', ME.message);
                    return
                end

                % Fallo real
                try
                    setBatchLamp('FAIL');
                catch
                end
                app.logMsgAlgorithm('ERROR', ME.message);
                uialert(app.UIFigure, sprintf('Batch failed:\n\n%s', ME.message), 'Batch error');
            end


        end

        % Button pushed function: Btn_DataFolderBrowse
        function Btn_DataFolderBrowseButtonPushed(app, event)
            app.logMsg('INFO','[UI] Button Data Folder Browse pressed.');

            % Directorio inicial
            startDir = pwd;
            if strlength(app.ProjectRoot)>0 && isfolder(app.ProjectRoot)
                startDir = char(app.ProjectRoot);
            end

            folder = uigetdir(startDir, 'Select Data folder');
            if isequal(folder,0)
                app.logMsg('INFO','Data folder selection canceled.');
                return; % cancelado
            end

            folder = string(folder);
            if ~isfolder(folder)
                uialert(app.UIFigure, 'Invalid folder selected.', 'Data folder');
                return
            end

            % Guardar
            app.ProjectRoot = folder;

            % Autocomplete por convención
            ivDefault  = fullfile(app.ProjectRoot,'data','iv');
            modDefault = fullfile(app.ProjectRoot,'config','modules');

            if isfolder(ivDefault)
                app.IVFolder = string(ivDefault);
                app.Btn_IVCurveFolderBrowse.Text    = 'data/iv';
                app.Btn_IVCurveFolderBrowse.Tooltip = char(ivDefault);
            end

            if isfolder(modDefault)
                app.ModulesFolder = string(modDefault);
                app.Btn_PVModuleFolderBrowse.Text    = 'config/modules';
                app.Btn_PVModuleFolderBrowse.Tooltip = char(modDefault);
            end

            app.logMsg('INFO', sprintf('ProjectRoot set: %s', app.ProjectRoot));

            if strlength(app.IVFolder)>0
                app.logMsg('INFO', sprintf('IVFolder auto: %s', app.IVFolder));
            end
            if strlength(app.ModulesFolder)>0
                app.logMsg('INFO', sprintf('ModulesFolder auto: %s', app.ModulesFolder));
            end

            try
                app.buildPaths();
                app.logMsg('INFO', sprintf('[PATHS] Synced. IVFolder=%s | ModulesFolder=%s', app.Paths.IVFolder, app.Paths.ModulesFolder));
            catch ME
                app.logMsg('WARN', sprintf('[PATHS] Sync skipped: %s', ME.message));
            end

        end

        % Button pushed function: Btn_IVCurveFolderBrowse
        function Btn_IVCurveFolderBrowseButtonPushed(app, event)
            app.logMsg('INFO','[UI] Button IVCurve Folder Browse pressed.');

            startDir = pwd;
            if strlength(app.IVFolder)>0 && isfolder(app.IVFolder)
                startDir = char(app.IVFolder);
            elseif strlength(app.ProjectRoot)>0 && isfolder(app.ProjectRoot)
                startDir = char(app.ProjectRoot);
            end

            folder = uigetdir(startDir,'Select I-V curve folder');
            if isequal(folder,0)
                app.logMsg('INFO','I-V folder selection canceled.');
                return
            end

            folder = string(folder);
            if ~isfolder(folder)
                uialert(app.UIFigure,'Invalid folder selected.','I-V folder');
                return
            end

            app.IVFolder = folder;

            [~,name] = fileparts(char(folder));
            if isempty(name), name = char(folder); end
            app.Btn_IVCurveFolderBrowse.Text = name;
            app.Btn_IVCurveFolderBrowse.Tooltip = char(folder);

            app.logMsg('INFO', sprintf('I-V folder set: %s', app.IVFolder));

            try
                app.buildPaths();
                app.logMsg('INFO', sprintf('[PATHS] Synced. IVFolder=%s | ModulesFolder=%s', app.Paths.IVFolder, app.Paths.ModulesFolder));
            catch ME
                app.logMsg('WARN', sprintf('[PATHS] Sync skipped: %s', ME.message));
            end


        end

        % Button pushed function: Btn_PVModuleFolderBrowse
        function Btn_PVModuleFolderBrowseButtonPushed(app, event)
            app.logMsg('INFO','[UI] Button PV Module Folder Browse pressed.');

            startDir = pwd;
            if strlength(app.ModulesFolder)>0 && isfolder(app.ModulesFolder)
                startDir = char(app.ModulesFolder);
            elseif strlength(app.ProjectRoot)>0 && isfolder(app.ProjectRoot)
                startDir = char(app.ProjectRoot);
            end

            folder = uigetdir(startDir,'Select PV module folder (config/modules)');
            if isequal(folder,0)
                app.logMsg('INFO','PV module folder selection canceled.');
                return
            end

            folder = string(folder);
            if ~isfolder(folder)
                uialert(app.UIFigure,'Invalid folder selected.','PV module folder');
                return
            end

            app.ModulesFolder = folder;

            [~,name] = fileparts(char(folder));
            if isempty(name), name = char(folder); end
            app.Btn_PVModuleFolderBrowse.Text = name;
            app.Btn_PVModuleFolderBrowse.Tooltip = char(folder);

            app.logMsg('INFO', sprintf('PV module folder set: %s', app.ModulesFolder));

            try
                app.buildPaths();
                app.logMsg('INFO', sprintf('[PATHS] Synced. IVFolder=%s | ModulesFolder=%s', app.Paths.IVFolder, app.Paths.ModulesFolder));
            catch ME
                app.logMsg('WARN', sprintf('[PATHS] Sync skipped: %s', ME.message));
            end

        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            try
                app.validatePaths();

                % Habilitar ejecución
                app.Btn_RunAlgorithm.Enable = 'on';
                app.Btn_RunBatch.Enable     = 'on';

                uialert(app.UIFigure, ...
                    'Validation completed successfully. The run is now enabled.', ...
                    'Validation successful', ...
                    'Icon', 'success');

                app.logMsg('INFO','Validation passed. Run enabled.');

            catch ME
                % Bloquear ejecución
                app.Btn_RunAlgorithm.Enable = 'off';
                app.Btn_RunBatch.Enable     = 'off';

                app.logMsg('ERROR', ME.message);

                uialert(app.UIFigure, ...
                    ME.message, ...
                    'Validation failed', ...
                    'Icon', 'error');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try
                app.validatePaths(); % obliga consistencia
                P = app.Paths;

                [seed,maxIter] = app.getAlgorithmControls();
                cfg = struct();
                cfg.Paths   = P;
                cfg.Seed    = seed;
                cfg.MaxIter = maxIter;
                cfg.SavedAt = datetime('now');

                [file, path] = uiputfile('project_config.mat','Save project config','project_config.mat');
                if isequal(file,0)
                    app.logMsg('INFO','Save config canceled.');
                    return
                end

                save(fullfile(path,file),'-struct','cfg');
                app.logMsg('INFO', sprintf('Config saved: %s', fullfile(path,file)));

            catch ME
                app.logMsg('ERROR', ME.message);
                uialert(app.UIFigure, ME.message, 'Save error');
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)

            % ===== App identity =====
            appName    = "PVMSim";
            appVersion = "0.1.0";
            repoURL    = "https://github.com/Liomnis/PVMSim";

            % ===== System info (solo datos) =====
            matlabFull = string(version);              % ej. "9.xx (R2025b)"
            osStr = "unknown";
            archStr = "unknown";
            try
                archStr = string(computer('arch'));
            end
            try
                osStr = string(system_dependent('getos'));
            end

            % ===== Session / project info =====
            tnow = string(datetime('now','Format','yyyy-MM-dd HH:mm'));

            pr = "(not set)";
            if isprop(app,'ProjectRoot') && strlength(app.ProjectRoot) > 0
                pr = app.ProjectRoot;
            end

            ivf = "(not set)";
            if isprop(app,'IVFolder') && strlength(app.IVFolder) > 0
                ivf = app.IVFolder;
            end

            mf = "(not set)";
            if isprop(app,'ModulesFolder') && strlength(app.ModulesFolder) > 0
                mf = app.ModulesFolder;
            end

            nCurves = 0;
            if isprop(app,'IVData') && ~isempty(app.IVData)
                nCurves = numel(app.IVData);
            end

            % ===== Compose message =====
            msg = [
                appName + " v" + appVersion
                "---------------------------------------"
                "Repository: " + repoURL
                ""
                "Environment Info:"
                "• MATLAB (full): " + matlabFull
                "• Platform: " + osStr
                "• Architecture: " + archStr
                ""
                "Session Info:"
                "• Time: " + tnow
                "• Project Root: " + pr
                "• I-V folder: " + ivf
                "• Modules folder: " + mf
                "• Loaded I-V curves: " + string(nCurves)
                ];

            % ===== Mostrar Alerta (Icono de Información) =====
            uialert(app.UIFigure, strjoin(msg, newline), "About " + appName, ...
                'Icon', 'info'); % <--- Cambia el triángulo de advertencia por el círculo de info

        end

        % Menu selected function: QuickguideUserManualMenu
        function QuickguideUserManualMenuSelected(app, event)
            url = 'https://github.com/Liomnis/PVMSim/blob/main/docs/workflow.md';
            try
                web(url, '-browser');   % abre en el navegador por defecto
                app.logMsg('INFO', sprintf('Opened workflow guide: %s', url));
            catch ME
                app.logMsg('ERROR', sprintf('Failed to open URL: %s | %s', url, ME.message));
                uialert(app.UIFigure, ...
                    sprintf('Could not open the link.\n\n%s', url), ...
                    'Open link failed');
            end
        end

        % Button pushed function: Btn_ExportAlgorithm
        function Btn_ExportAlgorithmButtonPushed(app, event)


            % ------------------------------
            % 1) Collect Algorithm log lines
            % ------------------------------
            v = app.AlgorithmLogTextArea.Value;

            if isstring(v)
                lines = v(:);
            elseif ischar(v)
                lines = string(v);
            elseif iscell(v)
                lines = string(v(:));
            else
                lines = string.empty;
            end

            % Remove empty lines (common initial state)
            lines = lines(strlength(strtrim(lines)) > 0);

            if isempty(lines)
                uialert(app.UIFigure, ...
                    'Algorithm log is empty. Nothing to export.', ...
                    'Export Algorithm Log');
                return
            end

            % ----------------------------------------------
            % 2) Default folder: <ProjectRoot>/outputs/exports
            % ----------------------------------------------
            exportDir = "";

            % Prefer ProjectRoot if set and valid
            if isprop(app,'ProjectRoot') && strlength(app.ProjectRoot)>0 && isfolder(app.ProjectRoot)
                exportDir = fullfile(char(app.ProjectRoot), 'outputs', 'exports');
            else
                % Fallback: derive root from app location (same logic as addProjectPaths)
                appFile = which('PVMSim');
                if isempty(appFile)
                    root = pwd;
                else
                    appDir = fileparts(appFile);
                    if endsWith(appDir, [filesep 'app'])
                        root = fileparts(appDir);
                    else
                        root = appDir;
                    end
                end
                exportDir = fullfile(root, 'outputs', 'exports');
            end

            % Ensure folder exists
            if ~isfolder(exportDir)
                mkdir(exportDir);
            end

            % -----------------------------------------
            % 3) Default filename with timestamp
            % -----------------------------------------
            ts = char(datetime('now','Format','yyyyMMdd_HHmmss'));
            defName = sprintf('algorithm_log_%s.txt', ts);

            % Open dialog in exportDir by default
            [file, path] = uiputfile( ...
                {'*.txt','Text file (*.txt)'; '*.log','Log file (*.log)'; '*.*','All files'}, ...
                'Export algorithm log', ...
                fullfile(exportDir, defName));

            if isequal(file,0)
                app.logMsg('INFO','Algorithm log export canceled.');
                return
            end

            out = fullfile(path, file);

            % ------------------------------
            % 4) Write file
            % ------------------------------
            try
                writelines(lines, out);
                app.logMsg('INFO', sprintf('Algorithm log exported: %s', out));
                app.logMsgAlgorithm('INFO', sprintf('Algorithm log exported: %s', out));
            catch ME
                app.logMsg('ERROR', sprintf('Algorithm log export failed: %s', ME.message));
                uialert(app.UIFigure, ME.message, 'Export failed');
            end

        end

        % Button pushed function: Btn_ExportMetricsSummary
        function Btn_ExportMetricsSummaryButtonPushed(app, event)
            % -------------------------
            % 0) Read and validate table
            % -------------------------
            if ~isprop(app,'Tbl_KPIs') || isempty(app.Tbl_KPIs) || ~isvalid(app.Tbl_KPIs)
                uialert(app.UIFigure,'KPIs table is not available.','Export Metrics');
                return
            end

            data = app.Tbl_KPIs.Data;
            if isempty(data)
                uialert(app.UIFigure,'KPIs table is empty. Nothing to export.','Export Metrics');
                return
            end

            % En tu app: ColumnName = {'Metric','Variable','Value','Unit'}
            colNames = app.Tbl_KPIs.ColumnName;
            if isempty(colNames)
                colNames = {'Metric','Variable','Value','Unit'};
            end

            % -------------------------
            % 1) Convert to table (robust)
            % -------------------------
            try
                % Asegurar cell
                if istable(data)
                    T = data;
                else
                    if ~iscell(data)
                        data = num2cell(data);
                    end
                    % Normaliza #cols
                    nCols = numel(colNames);
                    if size(data,2) < nCols
                        data(:, end+1:nCols) = {[]};
                    elseif size(data,2) > nCols
                        data = data(:,1:nCols);
                    end
                    T = cell2table(data, 'VariableNames', matlab.lang.makeValidName(string(colNames), 'ReplacementStyle','delete'));
                end
            catch ME
                uialert(app.UIFigure, sprintf('Failed to format KPI table for export:\n\n%s', ME.message), 'Export Metrics');
                return
            end

            % -------------------------
            % 2) Add minimal traceability metadata (SoftwareX-friendly)
            % -------------------------
            ts = char(datetime('now','Format','yyyyMMdd_HHmmss'));
            meta = struct();
            meta.AppName    = "PVMSim";
            meta.ExportedAt = string(datetime('now','Format','yyyy-MM-dd HH:mm:ss'));
            meta.MATLAB     = string(version('-release'));

            % Best-effort: include selected curve ID if available
            curveID = "";
            try
                if isprop(app,'SelectedCurveIndex') && isfinite(app.SelectedCurveIndex) && ...
                        isprop(app,'IVData') && app.SelectedCurveIndex>=1 && app.SelectedCurveIndex<=numel(app.IVData)
                    curveID = string(app.IVData{app.SelectedCurveIndex}.ID);
                end
            catch
                curveID = "";
            end
            meta.CurveID = curveID;

            % -------------------------
            % 3) Ask user where/what to export
            % -------------------------
            defBase = sprintf('kpis_%s', ts);
            [file, path, ~] = uiputfile( ...
                { ...
                '*.xlsx', 'Excel workbook (*.xlsx)'; ...
                '*.csv',  'CSV (comma-separated) (*.csv)'; ...
                '*.txt',  'Text (tab-delimited) (*.txt)'; ...
                '*.json', 'JSON metadata + data (*.json)'; ...
                '*.*',    'All files (*.*)' ...
                }, ...
                'Export KPIs table', ...
                [defBase '.xlsx']);

            if isequal(file,0)
                app.logMsg('INFO','KPIs export canceled.');
                return
            end

            out = fullfile(path, file);
            [~,~,ext] = fileparts(out);
            ext = lower(ext);

            % -------------------------
            % 4) Export by format
            % -------------------------
            try
                switch ext
                    case '.xlsx'
                        % Data sheet
                        writetable(T, out, 'Sheet', 'KPIs', 'WriteMode','overwritesheet');

                        % Metadata sheet
                        M = struct2table(meta);
                        writetable(M, out, 'Sheet', 'Metadata', 'WriteMode','overwritesheet');

                    case '.csv'
                        writetable(T, out);

                        % metadata sidecar (same basename)
                        metaPath = fullfile(path, [defBase '_metadata.json']);
                        fid = fopen(metaPath,'w');
                        if fid>0
                            fwrite(fid, jsonencode(meta, 'PrettyPrint', true));
                            fclose(fid);
                        end

                    case '.txt'
                        % Tab-delimited, good for reproducibility and diff
                        writetable(T, out, 'Delimiter','\t');

                        % prepend a short header file with metadata (sidecar)
                        metaPath = fullfile(path, [defBase '_metadata.txt']);
                        fid = fopen(metaPath,'w');
                        if fid>0
                            fprintf(fid, 'AppName:\t%s\n', meta.AppName);
                            fprintf(fid, 'ExportedAt:\t%s\n', meta.ExportedAt);
                            fprintf(fid, 'MATLAB:\t%s\n', meta.MATLAB);
                            fprintf(fid, 'CurveID:\t%s\n', meta.CurveID);
                            fclose(fid);
                        end

                    case '.json'
                        payload = struct();
                        payload.metadata = meta;
                        payload.kpis = table2struct(T);
                        fid = fopen(out,'w');
                        if fid<0
                            error('Cannot open JSON file for writing.');
                        end
                        fwrite(fid, jsonencode(payload, 'PrettyPrint', true));
                        fclose(fid);

                    otherwise
                        % Fallback: write as tab-delimited text
                        writetable(T, out, 'Delimiter','\t');
                end

                app.logMsg('INFO', sprintf('KPIs exported: %s', out));

            catch ME
                app.logMsg('ERROR', sprintf('KPIs export failed: %s', ME.message));
                uialert(app.UIFigure, ME.message, 'Export failed');
            end
        end

        % Button pushed function: Btn_ExportSummaryBatch
        function Btn_ExportSummaryBatchButtonPushed(app, event)
            % -------------------------
            % 0) Validate tables exist
            % -------------------------
            if ~isprop(app,'Tbl_BatchSummary') || isempty(app.Tbl_BatchSummary) || ~isvalid(app.Tbl_BatchSummary)
                uialert(app.UIFigure,'Batch summary table is not available.','Export Batch');
                return
            end
            if ~isprop(app,'Tbl_RunsBatch') || isempty(app.Tbl_RunsBatch) || ~isvalid(app.Tbl_RunsBatch)
                uialert(app.UIFigure,'Batch runs table is not available.','Export Batch');
                return
            end

            dataSum  = app.Tbl_BatchSummary.Data;
            dataRuns = app.Tbl_RunsBatch.Data;

            if isempty(dataSum) && isempty(dataRuns)
                uialert(app.UIFigure,'Both batch tables are empty. Nothing to export.','Export Batch');
                return
            end

            % Column names (fallbacks)
            cnSum = app.Tbl_BatchSummary.ColumnName;
            if isempty(cnSum)
                cnSum = {'Model','Total Runs','Best','Worst','Mean','Std','Time (s/run)'};
            end
            cnRuns = app.Tbl_RunsBatch.ColumnName;
            if isempty(cnRuns)
                cnRuns = {'Run','Seed','RMSE obj','Time (s)','Status'};
            end

            % -------------------------
            % 1) Convert to MATLAB tables
            % -------------------------
            try
                Tsum = localUITableToTable(dataSum, cnSum);
                Trun = localUITableToTable(dataRuns, cnRuns);
            catch ME
                uialert(app.UIFigure, sprintf('Failed to format batch tables for export:\n\n%s', ME.message), 'Export Batch');
                return
            end

            % -------------------------
            % 2) Minimal traceability metadata (SoftwareX-friendly)
            % -------------------------
            ts = char(datetime('now','Format','yyyyMMdd_HHmmss'));
            meta = struct();
            meta.AppName    = "PVMSim";
            meta.ExportedAt = string(datetime('now','Format','yyyy-MM-dd HH:mm:ss'));
            meta.MATLAB     = string(version('-release'));

            % Best-effort: include selected curve ID if available
            curveID = "";
            try
                if isprop(app,'SelectedCurveIndex') && isfinite(app.SelectedCurveIndex) && ...
                        isprop(app,'IVData') && app.SelectedCurveIndex>=1 && app.SelectedCurveIndex<=numel(app.IVData)
                    curveID = string(app.IVData{app.SelectedCurveIndex}.ID);
                end
            catch
                curveID = "";
            end
            meta.CurveID = curveID;

            % -------------------------
            % 3) Pick output file
            % -------------------------
            defName = sprintf('batch_export_%s.xlsx', ts);
            [file, path] = uiputfile( ...
                {'*.xlsx','Excel workbook (*.xlsx)'; '*.*','All files (*.*)'}, ...
                'Export batch tables (two sheets)', ...
                defName);

            if isequal(file,0)
                app.logMsg('INFO','Batch export canceled.');
                return
            end

            out = fullfile(path, file);

            % -------------------------
            % 4) Write Excel with separate sheets
            % -------------------------
            try
                % Sheet names (keep short for Excel compatibility)
                writetable(Tsum, out, 'Sheet', 'BatchSummary', 'WriteMode','overwritesheet');
                writetable(Trun, out, 'Sheet', 'RunLog',      'WriteMode','overwritesheet');

                M = struct2table(meta);
                writetable(M, out, 'Sheet', 'Metadata', 'WriteMode','overwritesheet');

                app.logMsg('INFO', sprintf('Batch tables exported (Excel): %s', out));
                app.logMsgAlgorithm('INFO', sprintf('Batch tables exported (Excel): %s', out));

            catch ME
                app.logMsg('ERROR', sprintf('Batch export failed: %s', ME.message));
                uialert(app.UIFigure, ME.message, 'Export failed');
            end

            % -------------------------
            % Local helper: convert uitable.Data + ColumnName to table
            % -------------------------
            function T = localUITableToTable(d, colNames)

                if isempty(d)
                    % Return empty table with the right headers
                    vn = matlab.lang.makeValidName(string(colNames), 'ReplacementStyle','delete');
                    T = cell2table(cell(0,numel(vn)), 'VariableNames', vn);
                    return
                end

                if istable(d)
                    T = d;
                    return
                end

                if ~iscell(d)
                    d = num2cell(d);
                end

                nCols = numel(colNames);
                if size(d,2) < nCols
                    d(:, end+1:nCols) = {[]};
                elseif size(d,2) > nCols
                    d = d(:,1:nCols);
                end

                vn = matlab.lang.makeValidName(string(colNames), 'ReplacementStyle','delete');
                T  = cell2table(d, 'VariableNames', vn);
            end
        end

        % Value changing function: Slider_Zoom
        function Slider_ZoomValueChanging(app, event)
            app.Slider_Zoom.Value = event.Value;  % para que refleje el drag en UI
            app.applyConvergenceZoom();

        end

        % Menu selected function: SaveScreenshotPDFMenu
        function SaveScreenshotPDFMenuSelected(app, event)
            [file, path] = uiputfile('*.pdf', 'Save app view as PDF', 'PVMSim_view.pdf');
            if isequal(file,0); return; end
            outPdf = fullfile(path, file);

            drawnow;

            % 1) Tomar el handle correcto para exportapp
            hFig = app.UIFigure;

            % 2) Validación mínima (evita “invalid handle”)
            if isempty(hFig) || ~isvalid(hFig) || ~isa(hFig,'matlab.ui.Figure')
                uialert(app.UIFigure, ...
                    'UIFigure handle is invalid. Ensure the app window is open and not deleted.', ...
                    'Export error');
                return;
            end

            try
                % En muchos releases, exportapp espera la FIGURA (no el objeto app)
                exportapp(hFig, outPdf);
            catch ME
                uialert(app.UIFigure, ...
                    sprintf(['exportapp failed.\n\n%s\n\n' ...
                    'Workaround: export each UIAxes as vector PDF using exportgraphics(UIAxes,...).'], ...
                    ME.message), ...
                    'Export error');
            end
        end

        % Menu selected function: ClearAllMenu
        function ClearAllMenuSelected(app, event)
            % Confirmación
            sel = uiconfirm(app.UIFigure, ...
                'This will clear all loaded data, results, and logs and restore the initial state. Continue?', ...
                'Clear All', ...
                'Options', {'Clear','Cancel'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);

            if ~strcmp(sel,'Clear')
                return
            end

            % Reset completo
            app.resetToInitialState();
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            % Optional confirmation (comment out if not needed)
            try
                choice = uiconfirm(app.UIFigure, ...
                    'Close PVMSim?', 'Exit', ...
                    'Options', {'Close','Cancel'}, ...
                    'DefaultOption', 2, ...
                    'CancelOption', 2);
                if ~strcmp(choice,'Close')
                    return
                end
            catch
                % If uiconfirm is not available or fails, continue without confirmation
            end

            % Close app
            try
                if ~isempty(app.UIFigure) && isvalid(app.UIFigure)
                    delete(app.UIFigure);   % triggers app delete() as well
                    return
                end
            catch
            end

            % Fallback
            try
                delete(app);
            catch
            end
        end

        % Button pushed function: Btn_CancelBatch
        function Btn_CancelBatchButtonPushed(app, event)
            app.BatchCancelRequested = true;

            % feedback inmediato
            try
                app.Lamp_Batch.Color = [1 1 0]; % yellow
                app.StatusLamp_2Label.Text = 'Canceling...';
            catch
            end

            app.logMsgAlgorithm('INFO','Batch cancel requested by user.');
            drawnow limitrate
        end

        % Button pushed function: Btn_ResetParams
        function Btn_ResetParamsButtonPushed(app, event)

            % Disable export params after reset
            if isprop(app,'Btn_ExportParams') && ~isempty(app.Btn_ExportParams) && isvalid(app.Btn_ExportParams)
                app.Btn_ExportParams.Enable = 'off';
            end

            % Mantener siempre columnas fijas (headers) y primera columna (Parameter)
            names = {'Iph','Io1','Io2','Rs','Rsh','n1','n2'};
            units = {'A','A','A','Ω','Ω','-','-'};

            % Reimponer layout (robusto ante createComponents con ColumnName={''})
            app.Tbl_ExtractedPar.RowName        = {};
            app.Tbl_ExtractedPar.ColumnName     = {'Parameter','Value','Unit'};
            app.Tbl_ExtractedPar.ColumnEditable = [false false false];
            app.Tbl_ExtractedPar.ColumnFormat   = {'char','char','char'};
            app.Tbl_ExtractedPar.ColumnWidth    = {80,'auto',40};

            % Reconstruir Data preservando primera columna y unidades
            d = cell(numel(names),3);
            for k = 1:numel(names)
                d{k,1} = names{k};               % Parameter (fijo)
                d{k,2} = 'Waiting to run...';    % Value (reset)
                d{k,3} = units{k};               % Unit (fijo)
            end
            app.Tbl_ExtractedPar.Data = d;

            % Opcional (si quieres trazabilidad en el log, igual que Btn_Reset)
            app.logMsg('INFO','Extracted parameters table reset.');
        end

        % Button pushed function: Btn_ExportParams
        function Btn_ExportParamsButtonPushed(app, event)

            % -------------------------
            % 0) Validate table content
            % -------------------------
            if ~isprop(app,'Tbl_ExtractedPar') || isempty(app.Tbl_ExtractedPar) || ~isvalid(app.Tbl_ExtractedPar)
                uialert(app.UIFigure,'Extracted parameters table is not available.','Export Parameters');
                return
            end

            data = app.Tbl_ExtractedPar.Data;
            if isempty(data) || size(data,2) < 3
                uialert(app.UIFigure,'Extracted parameters table is empty. Nothing to export.','Export Parameters');
                return
            end

            % Prevent exporting placeholder state
            try
                vals = string(data(:,2));
                if all(contains(vals, "Waiting to run...", 'IgnoreCase', true)) || ...
                        all(contains(vals, "Calculating...", 'IgnoreCase', true))
                    uialert(app.UIFigure,'Run the algorithm before exporting parameters.','Export Parameters');
                    return
                end
            catch
                % if conversion fails, continue and let export try
            end

            % ----------------------------------------------
            % 1) Default folder: <ProjectRoot>/outputs/exports
            % ----------------------------------------------
            exportDir = "";

            if isprop(app,'ProjectRoot') && strlength(app.ProjectRoot)>0 && isfolder(app.ProjectRoot)
                exportDir = fullfile(char(app.ProjectRoot), 'outputs', 'exports');
            else
                appFile = which('PVMSim');
                if isempty(appFile)
                    root = pwd;
                else
                    appDir = fileparts(appFile);
                    if endsWith(appDir, [filesep 'app'])
                        root = fileparts(appDir);
                    else
                        root = appDir;
                    end
                end
                exportDir = fullfile(root, 'outputs', 'exports');
            end

            if ~isfolder(exportDir)
                mkdir(exportDir);
            end

            % -------------------------
            % 2) Build export table
            % -------------------------
            param  = string(data(:,1));
            valRaw = string(data(:,2));
            unit   = string(data(:,3));

            % Best-effort numeric parsing (supports scientific notation)
            valNum = nan(numel(valRaw),1);
            for i = 1:numel(valRaw)
                x = str2double(strtrim(valRaw(i)));
                if isfinite(x)
                    valNum(i) = x;
                end
            end

            T = table(param, valRaw, valNum, unit, ...
                'VariableNames', {'Parameter','Value_raw','Value_num','Unit'});

            % -------------------------
            % 3) Minimal metadata sheet
            % -------------------------
            meta = struct();
            meta.AppName    = "PVMSim";
            meta.ExportedAt = string(datetime('now','Format','yyyy-MM-dd HH:mm:ss'));
            meta.MATLAB     = string(version('-release'));

            % Selected curve ID (best effort)
            curveID = "";
            try
                if isprop(app,'SelectedCurveIndex') && isfinite(app.SelectedCurveIndex) && ...
                        isprop(app,'IVData') && app.SelectedCurveIndex>=1 && app.SelectedCurveIndex<=numel(app.IVData)
                    curveID = string(app.IVData{app.SelectedCurveIndex}.ID);
                end
            catch
                curveID = "";
            end
            meta.CurveID = curveID;

            % Seed and MaxIter (best effort)
            try
                [seed, maxIter] = app.getAlgorithmControls();
                meta.Seed = seed;
                meta.MaxIter = maxIter;
            catch
            end

            % -------------------------
            % 4) Choose output file
            % -------------------------
            ts = char(datetime('now','Format','yyyyMMdd_HHmmss'));
            defName = sprintf('extracted_parameters_%s.xlsx', ts);

            [file, path] = uiputfile( ...
                {'*.xlsx','Excel workbook (*.xlsx)'}, ...
                'Export extracted parameters', ...
                fullfile(exportDir, defName));

            if isequal(file,0)
                app.logMsg('INFO','Parameters export canceled.');
                return
            end

            out = fullfile(path, file);

            % -------------------------
            % 5) Write Excel
            % -------------------------
            try
                writetable(T, out, 'Sheet', 'ExtractedParameters', 'WriteMode','overwritesheet');
                writetable(struct2table(meta), out, 'Sheet', 'Metadata', 'WriteMode','overwritesheet');

                app.logMsg('INFO', sprintf('Extracted parameters exported: %s', out));
                app.logMsgAlgorithm('INFO', sprintf('Extracted parameters exported: %s', out));
            catch ME
                app.logMsg('ERROR', sprintf('Parameters export failed: %s', ME.message));
                uialert(app.UIFigure, ME.message, 'Export failed');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.ClearAllMenu = uimenu(app.EditMenu);
            app.ClearAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearAllMenuSelected, true);
            app.ClearAllMenu.Separator = 'on';
            app.ClearAllMenu.Text = 'Clear All';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.SaveScreenshotPDFMenu = uimenu(app.ToolsMenu);
            app.SaveScreenshotPDFMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveScreenshotPDFMenuSelected, true);
            app.SaveScreenshotPDFMenu.Text = 'Save Screenshot (PDF)';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Separator = 'on';
            app.DocumentationMenu.Text = 'Documentation';
            app.QuickguideUserManualMenu = uimenu(app.DocumentationMenu);
            app.QuickguideUserManualMenu.MenuSelectedFcn = createCallbackFcn(app, @QuickguideUserManualMenuSelected, true);
            app.QuickguideUserManualMenu.Tooltip = {'https://github.com/Liomnis/PVMSim/blob/main/docs/workflow.md'};
            app.QuickguideUserManualMenu.Text = 'Quick guide / User Manual';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Separator = 'on';
            app.AboutMenu.Text = 'About';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MeasuredIVDataTab = uitab(app.TabGroup);
            app.MeasuredIVDataTab.Title = 'Measured I-V Data';
            app.Axes_IV_Meas = uiaxes(app.MeasuredIVDataTab);
            title(app.Axes_IV_Meas, 'I-V Curve: Measured ')
            xlabel(app.Axes_IV_Meas, 'Voltage, V (V)')
            ylabel(app.Axes_IV_Meas, 'Current, I (A)')
            zlabel(app.Axes_IV_Meas, 'Z')
            app.Axes_PV_Meas = uiaxes(app.MeasuredIVDataTab);
            title(app.Axes_PV_Meas, 'P-V Curve: Measured')
            xlabel(app.Axes_PV_Meas, 'Voltage, V (V)')
            ylabel(app.Axes_PV_Meas, 'Power, P (W)')
            zlabel(app.Axes_PV_Meas, 'Z')
            app.Tbl_IVCurves = uitable(app.MeasuredIVDataTab);
            app.Tbl_IVCurves.ColumnName = {'Reading ID'; 'G (W/m²)'; 'T (°C)'; 'Date'; 'Points'; 'Valid'};
            app.Tbl_IVCurves.RowName = {};
            app.Tbl_IVCurves.CellSelectionCallback = createCallbackFcn(app, @Tbl_IVCurvesCellSelection, true);
            app.Tbl_KeyPoints = uitable(app.MeasuredIVDataTab);
            app.Tbl_KeyPoints.ColumnName = {''};
            app.Tbl_KeyPoints.RowName = {'PmaxE'; 'Voc'; 'Vmpp'; 'Impp'; 'Isc'; 'Ki'; 'Kv'; 'Ns'; 'Np'; 'Large'; 'Anchure'};
            app.Btn_ImportIVmeas = uibutton(app.MeasuredIVDataTab, 'push');
            app.Btn_ImportIVmeas.ButtonPushedFcn = createCallbackFcn(app, @Btn_ImportIVmeasPushed, true);
            app.Btn_ImportIVmeas.Icon = fullfile(pathToMLAPP, 'import.svg');
            app.Btn_ImportIVmeas.Tooltip = {'Import the measured I–V curve file(s)'};
            app.Btn_ImportIVmeas.Text = 'Import';
            app.Btn_ResetIVmeas = uibutton(app.MeasuredIVDataTab, 'push');
            app.Btn_ResetIVmeas.ButtonPushedFcn = createCallbackFcn(app, @Btn_ResetIVmeasPushed, true);
            app.Btn_ResetIVmeas.Icon = fullfile(pathToMLAPP, 'reload.svg');
            app.Btn_ResetIVmeas.Text = 'Reset';
            app.KeyElectricalPointsSelectedIVCurveLabel = uilabel(app.MeasuredIVDataTab);
            app.KeyElectricalPointsSelectedIVCurveLabel.Text = 'Key Electrical Points (Selected I–V Curve)';
            app.MeasuredIVCurveDataLabel = uilabel(app.MeasuredIVDataTab);
            app.MeasuredIVCurveDataLabel.Text = 'Measured I–V Curve Data';
            app.AlgorithmTab = uitab(app.TabGroup);
            app.AlgorithmTab.Title = 'Algorithm';
            app.Axes_Fine_Conv = uiaxes(app.AlgorithmTab);
            title(app.Axes_Fine_Conv, 'Curve Convergence')
            xlabel(app.Axes_Fine_Conv, 'Iteration')
            ylabel(app.Axes_Fine_Conv, 'RMSE')
            zlabel(app.Axes_Fine_Conv, 'Z')
            app.Tbl_ExtractedPar = uitable(app.AlgorithmTab);
            app.Tbl_ExtractedPar.ColumnName = {''};
            app.Tbl_ExtractedPar.RowName = {};
            app.AlgorithmLogTextAreaLabel = uilabel(app.AlgorithmTab);
            app.AlgorithmLogTextAreaLabel.Text = 'Algorithm Log';
            app.AlgorithmLogTextArea = uitextarea(app.AlgorithmTab);
            app.Btn_RunAlgorithm = uibutton(app.AlgorithmTab, 'push');
            app.Btn_RunAlgorithm.ButtonPushedFcn = createCallbackFcn(app, @Btn_RunAlgorithmPushed, true);
            app.Btn_RunAlgorithm.Icon = fullfile(pathToMLAPP, 'run.svg');
            app.Btn_RunAlgorithm.Text = 'Run';
            app.StatusLampLabel = uilabel(app.AlgorithmTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.AlgorithmTab);
            app.ExtractedparametersLabel = uilabel(app.AlgorithmTab);
            app.ExtractedparametersLabel.Text = 'Extracted Parameters';
            app.NRunsLabel_2 = uilabel(app.AlgorithmTab);
            app.NRunsLabel_2.Text = 'Default seed';
            app.EditField_RandomSeed = uieditfield(app.AlgorithmTab, 'numeric');
            app.EditField_RandomSeed.Limits = [1 4294967295];
            app.EditField_RandomSeed.RoundFractionalValues = 'on';
            app.EditField_RandomSeed.Value = 42;
            app.NRunsLabel_3 = uilabel(app.AlgorithmTab);
            app.NRunsLabel_3.Text = 'No.iter';
            app.EditField_MaxIteration = uieditfield(app.AlgorithmTab, 'numeric');
            app.EditField_MaxIteration.Value = 80000;
            app.Btn_ExportAlgorithm = uibutton(app.AlgorithmTab, 'push');
            app.Btn_ExportAlgorithm.ButtonPushedFcn = createCallbackFcn(app, @Btn_ExportAlgorithmButtonPushed, true);
            app.Btn_ExportAlgorithm.Icon = fullfile(pathToMLAPP, 'save.svg');
            app.Btn_ExportAlgorithm.Text = 'Save';
            app.ZoomxaxesLabel = uilabel(app.AlgorithmTab);
            app.ZoomxaxesLabel.Text = {'Zoom '; 'X-axes (%)'};
            app.Slider_Zoom = uislider(app.AlgorithmTab);
            app.Slider_Zoom.ValueChangingFcn = createCallbackFcn(app, @Slider_ZoomValueChanging, true);
            app.Slider_Zoom.Value = 100;
            app.Btn_ResetParams = uibutton(app.AlgorithmTab, 'push');
            app.Btn_ResetParams.ButtonPushedFcn = createCallbackFcn(app, @Btn_ResetParamsButtonPushed, true);
            app.Btn_ResetParams.Icon = fullfile(pathToMLAPP, 'reload.svg');
            app.Btn_ResetParams.Tooltip = {'Reset data of PV Module Electrical Parameters (Datasheet)'};
            app.Btn_ResetParams.Text = 'Reset';
            app.Btn_ExportParams = uibutton(app.AlgorithmTab, 'push');
            app.Btn_ExportParams.ButtonPushedFcn = createCallbackFcn(app, @Btn_ExportParamsButtonPushed, true);
            app.Btn_ExportParams.Icon = fullfile(pathToMLAPP, 'save.svg');
            app.Btn_ExportParams.Text = 'Export';
            app.PlotsMetricsTab = uitab(app.TabGroup);
            app.PlotsMetricsTab.Title = 'Plots & Metrics';
            app.PlotsMetricsTab.Tag = ' ';
            app.Axes_PV_Meas_vs_Calc = uiaxes(app.PlotsMetricsTab);
            title(app.Axes_PV_Meas_vs_Calc, 'P-V Curve: Measured vs Calculated')
            xlabel(app.Axes_PV_Meas_vs_Calc, 'Voltage, V (V)')
            ylabel(app.Axes_PV_Meas_vs_Calc, 'Power, P (W)')
            zlabel(app.Axes_PV_Meas_vs_Calc, 'Z')
            app.Axes_IV_Meas_vs_Calc = uiaxes(app.PlotsMetricsTab);
            title(app.Axes_IV_Meas_vs_Calc, 'I-V Curve: Measured vs Calculated')
            xlabel(app.Axes_IV_Meas_vs_Calc, 'Voltage, V (V)')
            ylabel(app.Axes_IV_Meas_vs_Calc, 'Current, I (A)')
            zlabel(app.Axes_IV_Meas_vs_Calc, 'Z')
            app.Axes_Residuals_vs_Voltage = uiaxes(app.PlotsMetricsTab);
            title(app.Axes_Residuals_vs_Voltage, 'Current Residuals vs Voltage')
            xlabel(app.Axes_Residuals_vs_Voltage, 'Voltage, V (V)')
            ylabel(app.Axes_Residuals_vs_Voltage, 'Current Residual, Imeas − Icalc (A)')
            zlabel(app.Axes_Residuals_vs_Voltage, 'Z')
            app.Tbl_KPIs = uitable(app.PlotsMetricsTab);
            app.Tbl_KPIs.ColumnName = {''};
            app.Tbl_KPIs.RowName = {};
            app.Btn_ExportMetricsSummary = uibutton(app.PlotsMetricsTab, 'push');
            app.Btn_ExportMetricsSummary.ButtonPushedFcn = createCallbackFcn(app, @Btn_ExportMetricsSummaryButtonPushed, true);
            app.Btn_ExportMetricsSummary.Icon = fullfile(pathToMLAPP, 'export.svg');
            app.Btn_ExportMetricsSummary.Text = 'Export Metrics Summary';
            app.BatchandReport = uitab(app.TabGroup);
            app.BatchandReport.Title = 'Batch Runs';
            app.Tbl_BatchSummary = uitable(app.BatchandReport);
            app.Tbl_BatchSummary.ColumnName = {'Model'; 'Total Runs'; 'Best'; 'Worst'; 'Mean'; 'Std'; 'Time (s/run)'};
            app.Tbl_BatchSummary.RowName = {};
            app.ExtractedparametersLabel_2 = uilabel(app.BatchandReport);
            app.ExtractedparametersLabel_2.Text = 'Batch Run Summary';
            app.Btn_RunBatch = uibutton(app.BatchandReport, 'push');
            app.Btn_RunBatch.ButtonPushedFcn = createCallbackFcn(app, @Btn_RunBatchButtonPushed, true);
            app.Btn_RunBatch.Icon = fullfile(pathToMLAPP, 'run.svg');
            app.Btn_RunBatch.Tooltip = {'Run N repeated optimizations and update the batch summary tables'};
            app.Btn_RunBatch.Text = 'Run Batch';
            app.ExtractedparametersLabel_3 = uilabel(app.BatchandReport);
            app.ExtractedparametersLabel_3.Text = 'Run Status Table';
            app.Tbl_RunsBatch = uitable(app.BatchandReport);
            app.Tbl_RunsBatch.ColumnName = {'Run'; 'Seed'; 'RMSE obj'; 'Time (s)'; 'Status'};
            app.Tbl_RunsBatch.RowName = {};
            app.Btn_ExportSummaryBatch = uibutton(app.BatchandReport, 'push');
            app.Btn_ExportSummaryBatch.ButtonPushedFcn = createCallbackFcn(app, @Btn_ExportSummaryBatchButtonPushed, true);
            app.Btn_ExportSummaryBatch.Icon = fullfile(pathToMLAPP, 'export.svg');
            app.Btn_ExportSummaryBatch.Tooltip = {'Export the batch run summary and run log tables'};
            app.Btn_ExportSummaryBatch.Text = 'Export Summary ';
            app.StatusLamp_2Label = uilabel(app.BatchandReport);
            app.StatusLamp_2Label.Tag = 'StatusLamp_BatchLabel';
            app.StatusLamp_2Label.Text = 'Status';
            app.Lamp_Batch = uilamp(app.BatchandReport);
            app.NRunsLabel = uilabel(app.BatchandReport);
            app.NRunsLabel.Text = 'No. Runs';
            app.EditField_Nruns = uieditfield(app.BatchandReport, 'numeric');
            app.EditField_Nruns.Value = 3;
            app.CheckBox_Useconsecutiveseeds = uicheckbox(app.BatchandReport);
            app.CheckBox_Useconsecutiveseeds.Text = 'Consecutive Seeds';
            app.CheckBox_Useconsecutiveseeds.Value = true;
            app.Btn_CancelBatch = uibutton(app.BatchandReport, 'push');
            app.Btn_CancelBatch.ButtonPushedFcn = createCallbackFcn(app, @Btn_CancelBatchButtonPushed, true);
            app.Btn_CancelBatch.Icon = fullfile(pathToMLAPP, 'cancel.svg');
            app.Btn_CancelBatch.Text = 'Cancel';
            app.ProjectandDataPanel = uipanel(app.UIFigure);
            app.ProjectandDataPanel.Title = 'Project and Data';
            app.Tbl_Datasheet = uitable(app.ProjectandDataPanel);
            app.Tbl_Datasheet.ColumnName = {''};
            app.Tbl_Datasheet.RowName = {'PmaxE'; 'Voc'; 'Vmpp'; 'Impp'; 'Isc'; 'Ki'; 'Kv'; 'Ns'; 'Np'; 'Large'; 'Anchure'};
            app.Tbl_Datasheet.ColumnEditable = true;
            app.Btn_Import = uibutton(app.ProjectandDataPanel, 'push');
            app.Btn_Import.ButtonPushedFcn = createCallbackFcn(app, @Btn_ImportPushed, true);
            app.Btn_Import.Icon = fullfile(pathToMLAPP, 'import.svg');
            app.Btn_Import.Tooltip = {'Import data of PV module file(s)'};
            app.Btn_Import.Text = 'Import';
            app.Btn_Reset = uibutton(app.ProjectandDataPanel, 'push');
            app.Btn_Reset.ButtonPushedFcn = createCallbackFcn(app, @Btn_ResetPushed, true);
            app.Btn_Reset.Icon = fullfile(pathToMLAPP, 'reload.svg');
            app.Btn_Reset.Tooltip = {'Reset data of PV Module Electrical Parameters (Datasheet)'};
            app.Btn_Reset.Text = 'Reset';
            app.PVModuleElectricalParametersDatasheetLabel = uilabel(app.ProjectandDataPanel);
            app.PVModuleElectricalParametersDatasheetLabel.Text = 'PV Module Electrical Parameters (Datasheet)';
            app.ValidateButton = uibutton(app.ProjectandDataPanel, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Icon = fullfile(pathToMLAPP, 'validate.svg');
            app.ValidateButton.Tooltip = {'Validate the selected folders and input files to enable the algorithm run'};
            app.ValidateButton.Text = 'Validate';
            app.SaveButton = uibutton(app.ProjectandDataPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'save.svg');
            app.SaveButton.Tooltip = {'Save the current configuration'};
            app.SaveButton.Text = 'Save';
            app.Btn_DataFolderBrowse = uibutton(app.ProjectandDataPanel, 'push');
            app.Btn_DataFolderBrowse.ButtonPushedFcn = createCallbackFcn(app, @Btn_DataFolderBrowseButtonPushed, true);
            app.Btn_DataFolderBrowse.Icon = fullfile(pathToMLAPP, 'folderopen.svg');
            app.Btn_DataFolderBrowse.Tooltip = {'Selec the root data folder'};
            app.Btn_DataFolderBrowse.Text = 'Browse...';
            app.Btn_IVCurveFolderBrowse = uibutton(app.ProjectandDataPanel, 'push');
            app.Btn_IVCurveFolderBrowse.ButtonPushedFcn = createCallbackFcn(app, @Btn_IVCurveFolderBrowseButtonPushed, true);
            app.Btn_IVCurveFolderBrowse.Icon = fullfile(pathToMLAPP, 'folderopen.svg');
            app.Btn_IVCurveFolderBrowse.Tooltip = {'Select the folder that contains the measured I–V curve files'};
            app.Btn_IVCurveFolderBrowse.Text = 'Browse...';
            app.Btn_PVModuleFolderBrowse = uibutton(app.ProjectandDataPanel, 'push');
            app.Btn_PVModuleFolderBrowse.ButtonPushedFcn = createCallbackFcn(app, @Btn_PVModuleFolderBrowseButtonPushed, true);
            app.Btn_PVModuleFolderBrowse.Icon = fullfile(pathToMLAPP, 'folderopen.svg');
            app.Btn_PVModuleFolderBrowse.Tooltip = {'Select the folder that contains the PV module datasheet files'};
            app.Btn_PVModuleFolderBrowse.Text = 'Browse...';
            app.DatafolderLabel = uilabel(app.ProjectandDataPanel);
            app.DatafolderLabel.Text = 'Data folder';
            app.IVcurvefolderLabel = uilabel(app.ProjectandDataPanel);
            app.IVcurvefolderLabel.Text = 'I-V curve folder';
            app.PVmodulefolderLabel = uilabel(app.ProjectandDataPanel);
            app.PVmodulefolderLabel.Text = 'PV module folder';
            app.AppRunLogTextAreaLabel = uilabel(app.UIFigure);
            app.AppRunLogTextAreaLabel.Text = 'App Run Log';
            app.AppRunLogTextArea = uitextarea(app.UIFigure);
            app.Btn_ClearLog = uibutton(app.UIFigure, 'push');
            app.Btn_ClearLog.ButtonPushedFcn = createCallbackFcn(app, @Btn_ClearLogPushed, true);
            app.Btn_ClearLog.Icon = fullfile(pathToMLAPP, 'reload.svg');
            app.Btn_ClearLog.Text = 'Clear';
            app.Btn_CopyLog = uibutton(app.UIFigure, 'push');
            app.Btn_CopyLog.ButtonPushedFcn = createCallbackFcn(app, @Btn_CopyLogPushed, true);
            app.Btn_CopyLog.Icon = fullfile(pathToMLAPP, 'copy.svg');
            app.Btn_CopyLog.Text = 'Copy';
            app.Btn_ExportLog = uibutton(app.UIFigure, 'push');
            app.Btn_ExportLog.ButtonPushedFcn = createCallbackFcn(app, @Btn_ExportLogPushed, true);
            app.Btn_ExportLog.Icon = fullfile(pathToMLAPP, 'save.svg');
            app.Btn_ExportLog.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PVMSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end